classdef CometAnalyser_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        Menu_File                      matlab.ui.container.Menu
        Menu_File_OpenS                matlab.ui.container.Menu
        Menu_File_OpenF                matlab.ui.container.Menu
        Menu_File_LoadData             matlab.ui.container.Menu
        Menu_File_SaveData             matlab.ui.container.Menu
        Menu_Analysis                  matlab.ui.container.Menu
        Menu_Analysis_ExpMeasurements  matlab.ui.container.Menu
        Menu_Analysis_ExpSnapshots     matlab.ui.container.Menu
        Menu_Annotation                matlab.ui.container.Menu
        Menu_Annotation_Standard       matlab.ui.container.Menu
        Menu_Annotation_Assisted       matlab.ui.container.Menu
        Segmentation                   matlab.ui.container.Menu
        ExportAnnotationsMenu          matlab.ui.container.Menu
        TrainNewModelMenu              matlab.ui.container.Menu
        ImageSegmentationMenu          matlab.ui.container.Menu
        DatasetSegmentationMenu        matlab.ui.container.Menu
        CleanSegmentationMenu          matlab.ui.container.Menu
        OptionsMenu                    matlab.ui.container.Menu
        ClassificationMenu             matlab.ui.container.Menu
        ExportClassifierMenu           matlab.ui.container.Menu
        TrainClassifierMenu            matlab.ui.container.Menu
        PredictMenu                    matlab.ui.container.Menu
        OptionsMenu_2                  matlab.ui.container.Menu
        Menu_Help                      matlab.ui.container.Menu
        Menu_Help_About                matlab.ui.container.Menu
        but_Left                       matlab.ui.control.Button
        but_Right                      matlab.ui.control.Button
        text_Num                       matlab.ui.control.Label
        text_Name                      matlab.ui.control.Label
        uipanel1                       matlab.ui.container.Panel
        text5                          matlab.ui.control.Label
        but_CometAdd                   matlab.ui.control.Button
        but_CometRemove                matlab.ui.control.Button
        pop_class                      matlab.ui.control.DropDown
        text6                          matlab.ui.control.Label
        but_th_less                    matlab.ui.control.Button
        but_th_plus                    matlab.ui.control.Button
        text7                          matlab.ui.control.Label
        but_di_less                    matlab.ui.control.Button
        but_di_plus                    matlab.ui.control.Button
        but_SelectComet                matlab.ui.control.Button
        pop_tail                       matlab.ui.control.DropDown
        text8                          matlab.ui.control.Label
        pop_threshold                  matlab.ui.control.DropDown
        text_Threshold                 matlab.ui.control.Button
        text_Dilation                  matlab.ui.control.Button
        text11                         matlab.ui.control.Label
        text12                         matlab.ui.control.Label
        text13                         matlab.ui.control.Label
        text14                         matlab.ui.control.Label
        but_thHead_less                matlab.ui.control.Button
        but_thHead_plus                matlab.ui.control.Button
        text_ThresholdHead             matlab.ui.control.Button
        but_diHead_less                matlab.ui.control.Button
        but_diHead_plus                matlab.ui.control.Button
        text_DilationHead              matlab.ui.control.Button
        text17                         matlab.ui.control.Label
        check_CometFitFreehand         matlab.ui.control.CheckBox
        text18                         matlab.ui.control.Label
        pop_thresholdHead              matlab.ui.control.DropDown
        slider_comet_threshold         matlab.ui.control.Slider
        slider_comet_Dilation          matlab.ui.control.Slider
        slider_head_threshold          matlab.ui.control.Slider
        slider_head_Dilation           matlab.ui.control.Slider
        ClassBrowserButton             matlab.ui.control.Button
        Class_Create_Button            matlab.ui.control.Button
        Class_Remove_Button            matlab.ui.control.Button
        scope                          matlab.ui.control.Image
        but_ShowOriImg                 matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        axes1                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        comet_handles % Stores all commonly shared necessary data here
        selectedComet % Description
        CometIcon % Description
        imDatatipText % Description
        classBrowser_handle
        classCreationSuccessor; % Description
    end
    
    properties (Access = private)
        calculating = 0 % Description
    end
    
    methods (Access = private)
        
        function appTextDlg(app, text, title, type)
            if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end
            app.comet_handles.h_GUIhelpA = msgbox(sprintf(text),title,type); % type: 'help', 'warn', 'error', 'none'
            %To delete the button OK in the lower part of the window
            child = get(app.comet_handles.h_GUIhelpA,'Children');
            if isnumeric(child)
                delete(child(end))
            else
                delete(child(1))
            end
            pause(2*eps);
            InterfaceObj=findobj(app.figure1,'Enable','on');
            set(InterfaceObj,'Enable','off');
            waitfor(app.comet_handles.h_GUIhelpA);
            set(InterfaceObj,'Enable','on');
        end
        
        function reset_Comet_Handles(app)
            app.comet_handles.h_GUIhelpA = [];
            app.comet_handles.PathInputFolderOriginal = [];
            app.comet_handles.FluorescenceImages = 0;
            app.comet_handles.ImageFormat = [];
            app.comet_handles.ImageSize = [];
            app.comet_handles.NumImages = 0;
            app.comet_handles.Imgs_Ori = [];
            app.comet_handles.Imgs_Stretched = [];
            app.comet_handles.Imgs_Composed = [];
            app.comet_handles.NumImages = [];
            app.comet_handles.dirList = [];
            app.comet_handles.ImgsNames = [];
            app.comet_handles.IndImgShown = [];
            app.comet_handles.flag_CurrentCometType = 1;
            app.comet_handles.CurrentCometHead_YrowXcol = [];
            app.comet_handles.flag_ThresholdMode = 1; %1=Otsu, 2=Triangle, 3=average Otsu and Triangle.
            app.comet_handles.flag_ThresholdHeadMode = 1; %1=Otsu, 2=Triangle, 3=average Otsu and Triangle.
            app.comet_handles.CometThAddFactor = 0;
            app.comet_handles.CometSizeDiskDilation = 0;
            app.comet_handles.HeadThAddFactor = 0;
            app.comet_handles.HeadSizeDiskDilation = 0;
            app.comet_handles.ROIshown = 0;
            app.comet_handles.ROIori = [];
            app.comet_handles.ROIoriFiltered = [];
            app.comet_handles.ROIsegm = [];
            app.comet_handles.MaskComet = [];
            app.comet_handles.MaskHead = [];
            app.comet_handles.ROI_ULCyx_DRCyx = [];
            app.comet_handles.flag_CometFitFreehand = 0;
            app.comet_handles.ClassID = 0;
            app.comet_handles.SelectACometOngoing = 0;
            app.comet_handles.LoadImagesOngoing = 0;
            app.comet_handles.IntensityMinValStretched = 0;
            app.comet_handles.IntensityMaxValStretched = 255;
            app.comet_handles.IntensityMinValOrig = 0;
            app.comet_handles.IntensityMaxValOrig = 255;
            app.comet_handles.ROIminNumPixels = 25*25;
            app.comet_handles.FlagNewComets = 0;
            app.comet_handles.AutoSaveEveryNComets = 10;
            app.comet_handles.Classes = [];
            app.comet_handles.ClassCounter = 0;
        end
        
        function resetAx1(app)
            app.axes1.BackgroundColor = [0 0 0];
            app.axes1.XTickLabel = [];
            app.axes1.YTickLabel = [];
            app.axes1.XAxis.Visible = 'off';
            app.axes1.YAxis.Visible = 'off';
            app.axes1.Toolbar.Visible = "off";
            app.axes1.XLim = [0, app.comet_handles.ImageSize(2)];
            app.axes1.YLim = [0, app.comet_handles.ImageSize(1)];
        end
        
        
        function resetComponents(app)
            app.pop_class.Items = {'~No Class~'};
            app.pop_class.Value = '~No Class~';
            app.pop_tail.Value = app.pop_tail.Items(1);
            app.pop_threshold.Value = app.pop_threshold.Items(1);
            app.pop_thresholdHead.Value = app.pop_thresholdHead.Items(1);
            app.text_Dilation.Text = '0';
            app.text_DilationHead.Text = '0';
            app.text_Threshold.Text = '0';
            app.text_ThresholdHead.Text = '0';
            app.slider_comet_Dilation.Value = 0;
            app.slider_comet_threshold.Value = 0.5;
            app.slider_head_Dilation.Value = 0;
            app.slider_head_threshold.Value = 0.5;
            app.check_CometFitFreehand.Value = 0;
        end
    end
    
    methods (Access = public)
        
        function removeComet(app)
            if ~isempty(app.comet_handles.NumImages)
                if ~isempty(app.selectedComet)
                    
                    coor = app.selectedComet.param.thumbnailCoor;
                    mask = app.selectedComet.param.mask;
                    ImID = app.selectedComet.param.ImID;
                    ROI = app.comet_handles.Imgs_Composed(coor(1,1):coor(2,1), coor(2,2):coor(1,2), 4, ImID);
                    ROI(logical(mask)) = 0;
                    app.comet_handles.Imgs_Composed(coor(1,1):coor(2,1), coor(2,2):coor(1,2), 4, ImID) = ROI;
                    
                    ROIorig = app.comet_handles.Imgs_Stretched(coor(1,1):coor(2,1), coor(2,2):coor(1,2), 1, ImID);
                    app.comet_handles.Imgs_Composed(coor(1,1):coor(2,1), coor(2,2):coor(1,2), 1, ImID) = ROIorig;
                    app.comet_handles.Imgs_Composed(coor(1,1):coor(2,1), coor(2,2):coor(1,2), 2, ImID) = ROIorig;
                    app.comet_handles.Imgs_Composed(coor(1,1):coor(2,1), coor(2,2):coor(1,2), 3, ImID) = ROIorig;
                    
                    
                    numOfMembers = app.comet_handles.Classes.(app.selectedComet.className).num_el;
                    if numOfMembers <= 1
                        app.comet_handles.Classes.(app.selectedComet.className).Members.ImName = [];
                        app.comet_handles.Classes.(app.selectedComet.className).Members.ImID = [];
                        app.comet_handles.Classes.(app.selectedComet.className).Members.mask = [];
                        app.comet_handles.Classes.(app.selectedComet.className).Members.thumbnailCoor = [];
                        app.comet_handles.Classes.(app.selectedComet.className).num_el = 0;
                    else
                        maskCenterPoint = round([(coor(2, 1) + coor(1, 1))/2, (coor(2, 2) + coor(1, 2))/2]);
                        for z = 1:numOfMembers
                            memberCoor = app.comet_handles.Classes.(app.selectedComet.className).Members(z).thumbnailCoor;
                            if maskCenterPoint(1) > memberCoor(1, 1) && maskCenterPoint(1) < memberCoor(2, 1) && ...
                                    maskCenterPoint(2) > memberCoor(2, 2) && maskCenterPoint(2) < memberCoor(1, 2)
                            memberIdx = z;
                            break
                            end
                        end
                        app.comet_handles.Classes.(app.selectedComet.className).Members(memberIdx)= [];
                        app.comet_handles.Classes.(app.selectedComet.className).num_el = app.comet_handles.Classes.(app.selectedComet.className).num_el - 1;
                    end
                    app.scope.ImageSource = app.CometIcon;
                else
                    errorString = 'The selected comet has invalid classID that greater than the number of classes!';
                    appTextDlg(app, errorString, 'Corrupted class container', 'error')
                end
                
                imshow(uint8(app.comet_handles.Imgs_Composed(:, :, 1:3, app.comet_handles.IndImgShown)), [], 'Parent', app.axes1);
                resetAx1(app)
                
                % Reset the ROI
                
                app.comet_handles.ROIshown = 0;
                app.comet_handles.ROIori = [];
                app.comet_handles.ROIoriFiltered = [];
                app.comet_handles.ROIsegm = [];
                app.comet_handles.MaskComet = [];
                app.comet_handles.MaskHead = [];
                app.comet_handles.ROI_ULCyx_DRCyx = [];
            end
        end
        
        function addComet(app)
            if ~isempty(app.comet_handles.NumImages)
                if strcmp(app.pop_class.Value,'~No Class~')
                    warnString = 'Create a class first!';
                    appTextDlg(app, warnString, 'Message', 'error')
                    return
                elseif ~isempty(app.selectedComet)
                    warnString = 'This comet is classified already!';
                    appTextDlg(app, warnString, 'Message', 'warn')
                    return
                end
                
                if app.comet_handles.ROIshown == 1
                    
                    ROIori = app.comet_handles.ROIori;
                    if all(ROIori(:)==0) || all(ROIori(:)==255)
                        app.comet_handles.ROIshown = 0;
                        app.comet_handles.ROIori = [];
                        app.comet_handles.ROIoriFiltered = [];
                        app.comet_handles.ROIsegm = [];
                        app.comet_handles.MaskComet = [];
                        app.comet_handles.MaskHead = [];
                        app.comet_handles.ROI_ULCyx_DRCyx = [];
                        if exist('hFigFree2', 'var'); delete(hFigFree2); end
                        errorString = 'No comet has been detected.';
                        appTextDlg(app, errorString, 'Message', 'warn')
                        return
                    end
                    
                    MaskHead = app.comet_handles.MaskHead;
                    MaskComet = app.comet_handles.MaskComet;
                    flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
                    ROI_ULCyx_DRCyx = app.comet_handles.ROI_ULCyx_DRCyx;
                    ULC_Yrow_roi = ROI_ULCyx_DRCyx(1,1); ULC_Xcol_roi = ROI_ULCyx_DRCyx(1,2); DRC_Yrow_roi = ROI_ULCyx_DRCyx(1,3); DRC_Xcol_roi = ROI_ULCyx_DRCyx(1,4);
                    IndImgShown = app.comet_handles.IndImgShown;
                    Imgs_Composed = app.comet_handles.Imgs_Composed;
                    
                    
                    % To delete external pixels in case of perfect fit.
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end
                    
                    ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                    
                    app.scope.ImageSource = uint8(ROIcomposed);
                    
                    
                    [rowI, colI, ~, ~] = size(Imgs_Composed);
                    ImgComposedCh1 = Imgs_Composed(:, :, 1, IndImgShown);
                    ImgComposedCh2 = Imgs_Composed(:, :, 2, IndImgShown);
                    ImgComposedCh3 = Imgs_Composed(:, :, 3, IndImgShown);
                    ImgComposedCh4 = Imgs_Composed(:, :, 4, IndImgShown);
                    ImgMaskInd = zeros(rowI, colI);
                    ImgROIcomposedCh1 = zeros(rowI, colI);
                    ImgROIcomposedCh2 = zeros(rowI, colI);
                    ImgROIcomposedCh3 = zeros(rowI, colI);
                    ImgROIcomposedCh1(ULC_Yrow_roi:DRC_Yrow_roi, ULC_Xcol_roi:DRC_Xcol_roi) = ROIcomposed(:,:,1);
                    ImgROIcomposedCh2(ULC_Yrow_roi:DRC_Yrow_roi, ULC_Xcol_roi:DRC_Xcol_roi) = ROIcomposed(:,:,2);
                    ImgROIcomposedCh3(ULC_Yrow_roi:DRC_Yrow_roi, ULC_Xcol_roi:DRC_Xcol_roi) = ROIcomposed(:,:,3);
                    ImgMaskInd(ULC_Yrow_roi:DRC_Yrow_roi, ULC_Xcol_roi:DRC_Xcol_roi) = MaskComet;
                    Inds = find(ImgMaskInd==1);
                    
                    if isempty(Inds)
                        % Reset the ROI
                        
                        app.scope.ImageSource = app.CometIcon;
                        app.selectedComet = [];
                        app.comet_handles.ROIshown = 0;
                        app.comet_handles.ROIori = [];
                        app.comet_handles.ROIoriFiltered = [];
                        app.comet_handles.ROIsegm = [];
                        app.comet_handles.MaskComet = [];
                        app.comet_handles.MaskHead = [];
                        app.comet_handles.ROI_ULCyx_DRCyx = [];
                        if exist('hFigFree2', 'var'); delete(hFigFree2); end
                        errorString = 'No comet has been selected.';
                        appTextDlg(app, errorString, 'Message', 'warn')
                        return
                    end
                    
                    % Dilate the image of one pixel to be sure that the new comet is not
                    % touching and not overlapping to others.
                    ImgMaskInd_Dil = imdilate(ImgMaskInd, ones(3,3));
                    Inds_Dil = find(ImgMaskInd_Dil==1);
                    
                    %Check if other masks are already there
                    if max(ImgComposedCh1(Inds_Dil)-ImgComposedCh2(Inds_Dil))>0 || max(ImgComposedCh3(Inds_Dil)-ImgComposedCh2(Inds_Dil))>0
                        
                        app.scope.ImageSource = app.CometIcon;
                        app.selectedComet = [];
                        app.comet_handles.ROIshown = 0;
                        app.comet_handles.ROIori = [];
                        app.comet_handles.ROIoriFiltered = [];
                        app.comet_handles.ROIsegm = [];
                        app.comet_handles.MaskComet = [];
                        app.comet_handles.MaskHead = [];
                        app.comet_handles.ROI_ULCyx_DRCyx = [];
                        if exist('hFigFree2', 'var'); delete(hFigFree2); end
                        errorString = ['Two comets overlap or touch: it is not possible to save overlapping or touching comets.' ...
                            '\n Modify the segmentation parameters or remove the other previously segmented comet!'];
                        appTextDlg(app, errorString, 'Message', 'warn')
                        return
                    end
                    
                    ClassID = app.comet_handles.ClassID;
                    
                    ImgComposedCh1(Inds) = ImgROIcomposedCh1(Inds);
                    ImgComposedCh2(Inds) = ImgROIcomposedCh2(Inds);
                    ImgComposedCh3(Inds) = ImgROIcomposedCh3(Inds);
                    ImgComposedCh4(Inds) = ClassID;
                    Imgs_Composed(:, :, 1, IndImgShown) = ImgComposedCh1;
                    Imgs_Composed(:, :, 2, IndImgShown) = ImgComposedCh2;
                    Imgs_Composed(:, :, 3, IndImgShown) = ImgComposedCh3;
                    Imgs_Composed(:, :, 4, IndImgShown) = ImgComposedCh4;
                    imshow(uint8(Imgs_Composed(:,:,1:3,IndImgShown)), [], 'Parent', app.axes1);
                    resetAx1(app)
                    
                    [maskRow, maskCol] = find(ImgMaskInd_Dil==1);
                    
                    thumbnailCoor = [min(maskRow), max(maskCol);...
                        max(maskRow), min(maskCol)];
                    className = app.pop_class.Value;
                    if app.comet_handles.Classes.(className).num_el < 1
                        app.comet_handles.Classes.(className).Members.ImName = app.comet_handles.ImgsNames{IndImgShown};
                        app.comet_handles.Classes.(className).Members.ImID = IndImgShown;
                        app.comet_handles.Classes.(className).Members.thumbnailCoor = thumbnailCoor;
                        app.comet_handles.Classes.(className).Members.mask = ImgMaskInd(thumbnailCoor(1,1):thumbnailCoor(2,1),thumbnailCoor(2,2):thumbnailCoor(1,2));
                        app.comet_handles.Classes.(className).num_el = 1;
                    else
                        numOfElements = app.comet_handles.Classes.(className).num_el;
                        app.comet_handles.Classes.(className).Members(numOfElements+1).ImName = app.comet_handles.ImgsNames{IndImgShown};
                        app.comet_handles.Classes.(className).Members(numOfElements+1).ImID = IndImgShown;
                        app.comet_handles.Classes.(className).Members(numOfElements+1).thumbnailCoor = thumbnailCoor;
                        app.comet_handles.Classes.(className).Members(numOfElements+1).mask = ImgMaskInd(thumbnailCoor(1,1):thumbnailCoor(2,1),thumbnailCoor(2,2):thumbnailCoor(1,2));
                        app.comet_handles.Classes.(className).num_el = app.comet_handles.Classes.(className).num_el + 1;
                    end
                    
                    app.comet_handles.Imgs_Composed = Imgs_Composed;
                    app.comet_handles.FlagNewComets = app.comet_handles.FlagNewComets + 1;
                    
                    % Reset the ROI
                    
                    app.scope.ImageSource = app.CometIcon;
                    app.selectedComet = [];
                    app.comet_handles.ROIshown = 0;
                    app.comet_handles.ROIori = [];
                    app.comet_handles.ROIoriFiltered = [];
                    app.comet_handles.ROIsegm = [];
                    app.comet_handles.MaskComet = [];
                    app.comet_handles.MaskHead = [];
                    app.comet_handles.ROI_ULCyx_DRCyx = [];
                    if exist('hFigFree2', 'var'); delete(hFigFree2); end
                    
                    % Auto save of the project
                    if app.comet_handles.FlagNewComets>=app.comet_handles.AutoSaveEveryNComets
                        Menu_File_SaveData_Callback(app);
                    end
                    
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CometAnalyser_GUI_OpeningFcn(app, varargin)
            set(app.figure1, 'Name', 'CometAnalyser');
            app.CometIcon = imread('Logo_withCT_BK255.png');
            
            app.scope.ImageSource = app.CometIcon;
            app.selectedComet = [];
            reset_Comet_Handles(app)
            app.classBrowser_handle =[];
            app.axes1.Toolbar.Visible = "off";
            
            if ~isdeployed
                addpath(fullfile(pwd,'functions'))
                addpath(fullfile(pwd,'DATAtoBeExtracted'))
                addpath(fullfile(pwd,'DATAfunctionsRepository'))
                addpath(fullfile(pwd,'Segmentation'))
            end
        end

        % Menu selected function: Menu_Analysis_ExpMeasurements
        function Menu_Analysis_ExpMeasurements_Callback(app, event)
            if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end
            
            Imgs_Composed   = app.comet_handles.Imgs_Composed;
            Imgs_Stretched  = app.comet_handles.Imgs_Stretched;
            ImgsNames       = app.comet_handles.ImgsNames;
            
            if ~isempty(Imgs_Composed)
                [rowC, colC, chC, tC] = size(Imgs_Composed);
                [rowS, colS, ~, ~] = size(Imgs_Stretched);
                
                if chC<4 || rowC ~= rowS || colC ~= colS
                    errorString = 'No comet correctly added.';
                    appTextDlg(app, errorString, 'Message', 'warn')
                    return
                end
                
                ClassesPresent = unique(Imgs_Composed(:,:,4,:));
                if isempty(ClassesPresent) || (length(ClassesPresent)==1 && ClassesPresent(1)==0)
                    errorString = 'No comet correctly added.';
                    appTextDlg(app, errorString, 'Message', 'warn')
                    return
                end
                
                % Build output folders
                
                selpath = uigetdir;
                if isempty(selpath) || ~ischar(selpath)
                    errorString = 'Wrong path.';
                    appTextDlg(app, errorString, 'Error', 'error')
                    return
                end
                
                mkdir(fullfile(selpath,'LabeledFigures'));
                
                a = app.comet_handles.IntensityMinValStretched;
                b = app.comet_handles.IntensityMaxValStretched;
                c = app.comet_handles.IntensityMinValOrig;
                d = app.comet_handles.IntensityMaxValOrig;
                Intensity_MinMax_StretchedOrig = [a, b, c, d];
                
                % Find the comet
                wb = waitbar(0,sprintf('Exporting data.\n Image %d / %d',0,tC));
                for i = 1:tC
                    
                    Imgi_Comet  = Imgs_Composed(:,:,1,i);
                    Imgi_Orig   = Imgs_Composed(:,:,2,i);
                    Imgi_Head   = Imgs_Composed(:,:,3,i);
                    Imgi_Class  = Imgs_Composed(:,:,4,i);
                    Imgi_Class_BW = zeros(rowC, colC);
                    Imgi_Class_BW(Imgi_Class>0) = 1;
                    Imgi_Class_Labels = bwlabel(Imgi_Class_BW);
                    
                    LabelsPresent = unique(Imgi_Class_Labels(:));
                    if ~(isempty(LabelsPresent) || (length(LabelsPresent)==1 && LabelsPresent(1)==0))
                        
                        if LabelsPresent(1) == 0
                            LabelsPresent = LabelsPresent(2:end);
                        end
                        NumCometImagei = length(LabelsPresent);
                        
                        Imgi_Name = ImgsNames(i);
                        classNames = fieldnames(app.comet_handles.Classes);
                        classNameIdx = cellfun(@(s) app.comet_handles.Classes.(s).ID,classNames);
                        for j = 1:NumCometImagei
                            
                            [ind_Cometj_yrow, ind_Cometj_xcol] = find(Imgi_Class_Labels==LabelsPresent(j));
                            ULC = [min(ind_Cometj_yrow), min(ind_Cometj_xcol)];
                            DRC = [max(ind_Cometj_yrow), max(ind_Cometj_xcol)];
                            ROIw = max(ind_Cometj_xcol)-min(ind_Cometj_xcol)+1;
                            ROIh = max(ind_Cometj_yrow)-min(ind_Cometj_yrow)+1;
                            Imgi_Class_Labels_small = Imgi_Class_Labels(ULC(1):DRC(1),ULC(2):DRC(2));
                            Imgi_Class_small = Imgi_Class(ULC(1):DRC(1),ULC(2):DRC(2));
                            Imgi_Orig_small = Imgi_Orig(ULC(1):DRC(1),ULC(2):DRC(2));
                            Imgi_Head_small = Imgi_Head(ULC(1):DRC(1),ULC(2):DRC(2));
                            Imgi_Comet_small = Imgi_Comet(ULC(1):DRC(1),ULC(2):DRC(2));
                            ind_Cometj = find(Imgi_Class_Labels_small==LabelsPresent(j));
                            Maski_Cometj = zeros(ROIh, ROIw);
                            Maski_Headj  = zeros(ROIh, ROIw);
                            Maski_Tailj  = zeros(ROIh, ROIw);
                            Maski_Origj  = zeros(ROIh, ROIw);
                            Maski_Cometj(ind_Cometj) = 1;
                            Maski_Delet = zeros(ROIh, ROIw); Maski_Delet(ind_Cometj) = Imgi_Comet_small(ind_Cometj);
                            Maski_Tailj(Maski_Delet==255) = 1;
                            Maski_Delet = zeros(ROIh, colC); Maski_Delet(ind_Cometj) = Imgi_Head_small(ind_Cometj);
                            Maski_Headj(Maski_Delet==255) = 1;
                            Maski_Tailj(Maski_Delet==255) = 0; %To delete pixels originally at maximum value but not in the real tail
                            Maski_Origj(ind_Cometj) = Imgi_Orig_small(ind_Cometj);
                            Maski_Classj = unique(Imgi_Class_small(ind_Cometj));
                            if length(Maski_Classj)>1
                                try close(BarWaitWindows); catch; end
                                errorString = ['Error in image: ' num2str(i) ' comet: ' num2str(j)];
                                appTextDlg(app, errorString, 'Error', 'error')
                                return
                            end
                            
                            sizezeropad = max([ROIh, ROIw])+2;
                            Maski_Cometj_zeropad = zeros(ROIh+2*sizezeropad, ROIw+2*sizezeropad);
                            Maski_Headj_zeropad  = zeros(ROIh+2*sizezeropad, ROIw+2*sizezeropad);
                            Maski_Tailj_zeropad  = zeros(ROIh+2*sizezeropad, ROIw+2*sizezeropad);
                            Maski_Origj_zeropad  = zeros(ROIh+2*sizezeropad, ROIw+2*sizezeropad);
                            Maski_Cometj_zeropad(sizezeropad+1:end-sizezeropad, sizezeropad+1:end-sizezeropad) = Maski_Cometj;
                            Maski_Headj_zeropad(sizezeropad+1:end-sizezeropad, sizezeropad+1:end-sizezeropad) = Maski_Headj;
                            Maski_Tailj_zeropad(sizezeropad+1:end-sizezeropad, sizezeropad+1:end-sizezeropad) = Maski_Tailj;
                            Maski_Origj_zeropad(sizezeropad+1:end-sizezeropad, sizezeropad+1:end-sizezeropad) = Maski_Origj;
                            
                            % Compute parameters
                            Tableij = computeFeatures(Maski_Origj_zeropad, Maski_Cometj_zeropad, Maski_Tailj_zeropad, Maski_Headj_zeropad,...
                                classNames{classNameIdx==Maski_Classj}, Imgi_Name, Intensity_MinMax_StretchedOrig, j);
                            
                            if ~exist('TableAll', 'var')
                                for fn = fieldnames(Tableij)'
                                    TableAll.(fn{1,1}) = Tableij.(fn{1,1});
                                end
                            else
                                TableAll = [TableAll, Tableij]; %#ok<AGROW>
                            end
                        end
                        
                        Imgi_Class_BW_withNumbers_ch1 = Imgi_Orig;
                        Imgi_Class_BW_withNumbers_ch2 = Imgi_Orig;
                        Imgi_Class_BW_withNumbers_ch3 = Imgi_Orig;
                        Imgi_Class_BW_withNumbers_ch2(Imgi_Class_BW>0) = 255;
                        Imgi_Class_BW_withNumbers(:,:,1) = Imgi_Class_BW_withNumbers_ch1;
                        Imgi_Class_BW_withNumbers(:,:,2) = Imgi_Class_BW_withNumbers_ch2;
                        Imgi_Class_BW_withNumbers(:,:,3) = Imgi_Class_BW_withNumbers_ch3;
                        for u = 1:NumCometImagei
                            BWimageu = uint8(zeros(rowC, colC));
                            BWimageu(Imgi_Class_Labels==u) = 1;
                            CentroidCoordinates = struct2cell(regionprops(BWimageu,'Centroid'));
                            Imgi_Class_BW_withNumbers = AddTextToImage(Imgi_Class_BW_withNumbers,num2str(u),ceil([CentroidCoordinates{1,1}(2)-20, CentroidCoordinates{1,1}(1)-10]),[0, 0, 0],'Arial',40);
                        end
                        imwrite(Imgi_Class_BW_withNumbers, fullfile(selpath,'LabeledFigures', char(Imgi_Name)));
                        
                    end
                    if ishandle(wb)
                        waitbar(i/tC,wb,sprintf('Exporting data.\n Image %d / %d',i,tC))
                    else
                        wb = waitbar(i/tC,sprintf('Exporting data.\n Image %d / %d',i,tC));
                    end
                end
                if ishandle(wb), close(wb), end
                if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end
                
                if exist('TableAll', 'var')
                    showDataExtracted(TableAll)
                else
                    % Exporting data message
                    errorString = 'No segmented comets.';
                    appTextDlg(app, errorString, 'Message', 'warn')
                end
                
            end
        end

        % Menu selected function: Menu_Analysis_ExpSnapshots
        function Menu_Analysis_ExpSnapshots_Callback(app, event)
            if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end
            if app.comet_handles.ClassCounter
                Imgs_Composed   = app.comet_handles.Imgs_Composed;
                Imgs_Stretched  = app.comet_handles.Imgs_Stretched;
                
                if ~isempty(Imgs_Composed)
                    % Build output folders
                    selpath = uigetdir;
                    mkdir(fullfile(selpath,'CometSnapshots'));
                    classNames = fieldnames(app.comet_handles.Classes);
                    for j = 1:app.comet_handles.ClassCounter
                        mkdir(fullfile(selpath,'CometSnapshots', classNames{j}));
                    end
                    
                    % Save snapshot
                    for cl = 1:numel(classNames)
                        numimgs = app.comet_handles.Classes.(classNames{cl}).num_el;
                        for i = 1:numimgs
                            coor = app.comet_handles.Classes.(classNames{cl}).Members(i).thumbnailCoor;
                            imID = app.comet_handles.Classes.(classNames{cl}).Members(i).ImID;
                            snapshot = Imgs_Stretched(coor(1,1):coor(2,1), coor(2,2):coor(1,2),1,imID);
                            imwrite(snapshot, fullfile(selpath,'CometSnapshots', classNames{cl}, ['Comet_Image_' num2str(i) '_' classNames{cl} '.tiff']));
                        end
                    end
                end
            end
        end

        % Menu selected function: Menu_File_LoadData
        function Menu_File_LoadData_Callback(app, event)
            if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end
            
            app.scope.ImageSource = app.CometIcon;
            app.selectedComet = [];
            app.comet_handles.Imgs_Stretched = [];
            app.comet_handles.Imgs_Composed = [];
            app.comet_handles.NumImages = [];
            app.comet_handles.dirList = [];
            app.comet_handles.ImgsNames = [];
            app.comet_handles.IndImgShown = [];
            app.comet_handles.ROIshown = 0;
            app.comet_handles.ROIori = [];
            app.comet_handles.ROIoriFiltered = [];
            app.comet_handles.ROIsegm = [];
            app.comet_handles.ROI_ULCyx_DRCyx = [];
            app.comet_handles.ImageSize = [];
            
            resetComponents(app);
            
            [fileName, PathInputFolder] = uigetfile({'*.mat;'}, 'Select a previously saved datased (.mat)', 'MultiSelect', 'off');
            if ~isempty(PathInputFolder) && all(PathInputFolder~=0)
                VariableOpen = load([PathInputFolder, fileName]);
                app.comet_handles = VariableOpen.comet_handles;
                %                 ImgsNames = VariableOpen.ImgsNames;
                
                %                 [imHigh, imWidth, imDepth, NumImages] = size(Imgs_Composed);
                %                 app.comet_handles.ImageSize = [imHigh, imWidth];
                
                %                 Imgs_Stretched = uint8(zeros(imHigh, imWidth, imDepth, NumImages));
                %                 Imgs_Stretched(:,:,1,:) = Imgs_Composed(:,:,2,:);
                
                % Min and Max values
                try
                    %                     app.comet_handles.IntensityMinValStretched = VariableOpen.IntensityMinValStretched;
                    %                     app.comet_handles.IntensityMaxValStretched = VariableOpen.IntensityMaxValStretched;
                    %                     app.comet_handles.IntensityMinValOrig = VariableOpen.IntensityMinValOrig;
                    %                     app.comet_handles.IntensityMaxValOrig = VariableOpen.IntensityMaxValOrig;
                    %                     app.comet_handles.Classes = VariableOpen.Classes;
                    classNames = fieldnames(app.comet_handles.Classes);
                    app.pop_class.Items = classNames;
                catch
                    try close(BarWaitWindows); catch; end
                    errorString = 'The loaded file does not contain info about the original intensities. \nThe intensity values exported will be normalized between 0 and 255.';
                    appTextDlg(app, errorString, 'Message', 'warn')
                    app.comet_handles.IntensityMinValStretched = 0;
                    app.comet_handles.IntensityMaxValStretched = 255;
                    app.comet_handles.IntensityMinValOrig = 0;
                    app.comet_handles.IntensityMaxValOrig = 255;
                end
                
                % Save parameters
                %                 app.comet_handles.Imgs_Ori = Imgs_Ori; %Comment to save memory
                %                 app.comet_handles.Imgs_Stretched = Imgs_Stretched;
                %                 app.comet_handles.Imgs_Composed = Imgs_Composed;
                %                 app.comet_handles.NumImages = NumImages;
                %                 app.comet_handles.ImgsNames = ImgsNames;
                %                 app.comet_handles.IndImgShown = 1;
                %                 app.comet_handles.FluorescenceImages = 1;
                set(app.text_Num,'Text', ['Image: ' '1' '/' num2str(app.comet_handles.NumImages)]);
                set(app.text_Name,'Text', ['Image: ' char(app.comet_handles.ImgsNames{1})]);
                imshow(uint8(app.comet_handles.Imgs_Composed(:,:,1:3,1)), [], 'Parent', app.axes1);
                resetAx1(app)
            end
        end

        % Menu selected function: Menu_File_OpenF
        function Menu_File_OpenF_Callback(app, event)
            app.comet_handles.FluorescenceImages = 1;
            Menu_File_OpenS_Callback(app);
        end

        % Menu selected function: Menu_File_OpenS
        function Menu_File_OpenS_Callback(app, event)
            if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end
            
            
            app.scope.ImageSource = app.CometIcon;
            app.selectedComet = [];
            
            if app.comet_handles.LoadImagesOngoing == 1
                return
            end
            
            [ImageName, PathInputFolder] = uigetfile({'*.tif;*.tiff;*.bmp;*.png;*.jpg;'}, 'Select in the input folder one of the images to be processed', 'MultiSelect', 'off', app.comet_handles.PathInputFolderOriginal);
            if ~isempty(PathInputFolder) && all(PathInputFolder~=0)
                app.comet_handles.LoadImagesOngoing = 1;
                if app.comet_handles.FluorescenceImages == 0
                    errorString = 'Images will be displayed with black background.';
                    appTextDlg(app, errorString, 'Message', 'help')
                end
                
                try
                    % Read Path, name, Format
                    splitName = split(ImageName, '.');
                    ImageFormat = splitName{end};
                    
                    % Check if the input images are gray-level 8-bit images
                    dirList = dir([PathInputFolder, '*.' ImageFormat]);
                    ImgsNames = {dirList(:).name}';
                    NumImages = length(dirList(:));
                    imInfo = imfinfo(fullfile(PathInputFolder, ImageName));
                    Imgs_Ori = uint8(zeros(imInfo.Height, imInfo.Width, 1, NumImages));
                    wb = waitbar(0,sprintf('Importing images. Please wait! \n %d / %d Images', 0, NumImages));
                    for i=1:NumImages
                        referenceFrame = imread(fullfile(PathInputFolder, dirList(i).name));
                        [row, col, ch, t] = size(referenceFrame);
                        if i>1 && (row_old ~= row || col_old ~= col || ch_old ~= ch || t_old ~= t)
                            errorString = ['All the input images must be of the same size. \nImage: "' char(dirList(i).name) '" is of a different size.'];
                            appTextDlg(app, errorString, 'Error', 'error')
                            return
                        end
                        flag_error1 = 0;
                        if ~isa(referenceFrame, 'uint8')
                            flag_error1 = 1;
                        end
                        if size(referenceFrame, 3)~=1
                            flag_error1 = 1;
                            if i==1 % only at the first image occurence
                                inputOptions={'Red','Green','Blue','Gray conversion'};
                                defSelection=inputOptions{4};
                                iSel=bttnChoiseDialog(inputOptions, 'Load images', defSelection,...
                                    'Which channel do you want load?');
                                flag_channelToLoad = iSel;
                            end
                            if flag_channelToLoad == 1 || flag_channelToLoad == 2 || flag_channelToLoad == 3
                                referenceFrame = referenceFrame(:,:,flag_channelToLoad);
                            elseif flag_channelToLoad == 4
                                referenceFrame = rgb2gray(referenceFrame);
                            else
                                errorString = 'Incorrect selection.';
                                appTextDlg(app, errorString, 'Error', 'error')
                            end
                        end
                        if flag_error1==1 && i==1
                            errorString = 'The input images must be gray-level 8-bit images. \nThe images have been converted accordingly.';
                            appTextDlg(app, errorString, 'Message', 'help')
                        end
                        Imgs_Ori(:,:,1,i) = uint8(referenceFrame);
                        clear flag_error1 referenceFrame
                        row_old = row; col_old = col; ch_old = ch; t_old = t;
                        if ishandle(wb)
                            waitbar(i/NumImages,wb,sprintf('Importing images. Please wait! \n %d / %d Images', i, NumImages));
                        else
                            wb = waitbar(i,sprintf('Importing images. Please wait! \n %d / %d Images', i, NumImages));
                        end
                    end
                    if ishandle(wb), close(wb), end
                    
                    % Compute MinValue and MaxValue
                    MinValue = min(min(min(Imgs_Ori)));
                    MaxValue = max(max(max(Imgs_Ori)));
                    
                    % Update MinValue and MaxValue
                    if MaxValue <= MinValue
                        errorString = 'The minimum and the maximum values of the input images are coincident.';
                        appTextDlg(app, errorString, 'Error', 'error')
                        return
                    end
                    
                    % Check for seeing if the user click on the correct load button
                    flagEstimated_FluorescenceImages = checkFluorescentImages(Imgs_Ori(:,:,1,i));
                    if flagEstimated_FluorescenceImages ~= app.comet_handles.FluorescenceImages
                        % Construct a questdlg with two options
                        if app.comet_handles.FluorescenceImages==1
                            choice = questdlg('Are you sure that you loaded fluorescent images?', ...
                                'Check input images', ...
                                'Yes','No','Yes');
                        else
                            choice = questdlg('Are you sure that you loaded non-fluorescent images?', ...
                                'Check input images', ...
                                'Yes','No','Yes');
                        end
                        % Handle response
                        switch choice
                            case 'Yes'
                            otherwise
                                errorString = 'Please, re-load the images using the correct button.';
                                appTextDlg(app, errorString, 'Error', 'error')
                                clear Imgs_Ori MinValue MaxValue referenceFrame ImageName PathInputFolder dirList
                                app.comet_handles.LoadImagesOngoing = 0;
                                app.comet_handles.FluorescenceImages = 0;
                                return
                        end
                    end
                    
                    % To leave for masks saving purposes the value 255
                    a = double(0);   %Theoretically->double(0);
                    b = double(255); %Theoretically->double(255);
                    % Save the images
                    c = double(MinValue);
                    d = double(MaxValue);
                    
                    % Modify the original images by stretching
                    Imgs_Ori(Imgs_Ori>MaxValue) = MaxValue;
                    Imgs_Ori(Imgs_Ori<MinValue) = MinValue;
                    Imgs_Ori = ((Imgs_Ori-c).*((b-a)/(d-c)))+a;
                    if app.comet_handles.FluorescenceImages == 0
                        Imgs_Ori = imcomplement(Imgs_Ori);
                    end
                    Imgs_Composed = uint8(cat(3,Imgs_Ori,Imgs_Ori,Imgs_Ori, zeros(row, col, 1, NumImages)));
                    
                    reset_Comet_Handles(app)
                    
                    % Save parameters
                    app.comet_handles.IntensityMinValStretched = a;
                    app.comet_handles.IntensityMaxValStretched = b;
                    app.comet_handles.IntensityMinValOrig = c;
                    app.comet_handles.IntensityMaxValOrig = d;
                    app.comet_handles.Imgs_Stretched = Imgs_Ori;
                    app.comet_handles.Imgs_Composed = Imgs_Composed;
                    app.comet_handles.NumImages = NumImages;
                    app.comet_handles.ImageFormat = ImageFormat;
                    [imHigh, imWidth, ~, ~] = size(Imgs_Composed);
                    app.comet_handles.ImageSize = [imHigh, imWidth];
                    app.comet_handles.dirList = dirList;
                    app.comet_handles.ImgsNames = ImgsNames;
                    app.comet_handles.IndImgShown = 1;
                    app.comet_handles.PathInputFolderOriginal = PathInputFolder;
                    set(app.text_Num,'Text', ['Image: ' '1' '/' num2str(NumImages)]);
                    set(app.text_Name,'Text', ['Image: ' char(app.comet_handles.dirList(ceil(1)).name)]);
                    currentIm = imshow(Imgs_Composed(:,:,1:3,1), [], 'Parent', app.axes1);
                    app.axes1.XLim = [0 currentIm.XData(2)];
                    app.axes1.YLim = [0 currentIm.YData(2)];
                    resetAx1(app)
                    resetComponents(app);
                    
                catch
                    errorString = ['Please, select an image. \n' '\n' 'The input folder must contain only images and no other files. \n' '\n' 'The basename of the images cannot contain spaces and special characters, and must be in the format: "BasenameImage_###.ImageFormat".'];
                    appTextDlg(app, errorString, 'Error', 'error')
                    app.comet_handles.LoadImagesOngoing = 0;
                    app.comet_handles.FluorescenceImages = 0;
                end
                
            else
                app.comet_handles.FluorescenceImages = 0;
            end
        end

        % Menu selected function: Menu_File_SaveData
        function Menu_File_SaveData_Callback(app, event)
            %             ImgsNames = app.comet_handles.ImgsNames; %#ok<NASGU>
            %             Imgs_Composed = app.comet_handles.Imgs_Composed; %#ok<NASGU>
            %             IntensityMaxValOrig = app.comet_handles.IntensityMaxValOrig; %#ok<NASGU>
            %             IntensityMinValOrig = app.comet_handles.IntensityMinValOrig; %#ok<NASGU>
            %             IntensityMaxValStretched = app.comet_handles.IntensityMaxValStretched; %#ok<NASGU>
            %             IntensityMinValStretched = app.comet_handles.IntensityMinValStretched; %#ok<NASGU>
            %             Classes = app.comet_handles.Classes; %#ok<NASGU>
            comet_handles = app.comet_handles;
            
            % Auto save of the project
            if app.comet_handles.FlagNewComets>=app.comet_handles.AutoSaveEveryNComets
                %                 uisave({'Imgs_Composed','ImgsNames','IntensityMaxValOrig','IntensityMinValOrig','IntensityMaxValStretched','IntensityMinValStretched','Classes'},'Autosave_AnalysedDataset.mat');
                uisave({'comet_handles'},'Autosave_AnalysedDataset.mat');
            else
                %                 uisave({'Imgs_Composed','ImgsNames','IntensityMaxValOrig','IntensityMinValOrig','IntensityMaxValStretched','IntensityMinValStretched','Classes'},'AnalysedDataset.mat');
                uisave({'comet_handles'},'AnalysedDataset.mat');
            end
            app.comet_handles.FlagNewComets = 0;
        end

        % Menu selected function: Menu_Help_About
        function Menu_Help_About_Callback(app, event)
            Message = ['SOFTWARE VERSION\n',...
                'CometAnalyser v1.0\n',...
                'Oct 25, 2020\n',...
                '\n',...
                '\n',...
                'REQUIREMENTS\n',...
                'MATLAB R2020a and Image Processing Toolbox 10.1 or later versions.\n',...
                '\n',...
                '\n',...
                'MAIN CONTACTS\n',...
                'Eng. Filippo Piccinini, PhD, email: filippo.piccinini85@gmail.com\n',...
                'Eng. Attila Beleon, email: beleonattila@gmail.com\n',...
                '\n',...
                '\n',...
                'LICENSE',...
                'Copyright (c) 2017, Filippo Piccinini.\n',...
                'All rights reserved.\n',...
                'This program is free software: you can redistribute it and/or modify it under the terms of the GNU General Public License as published by the Free Software Foundation, either version 3 of the License, or (at your option) any later version.\n',...
                'This program is distributed in the hope that it will be useful, but WITHOUT ANY WARRANTY; without even the implied warranty of MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the GNU General Public License for more details.\n',...
                'You should have received a copy of the GNU General Public License along with this program. If not, see <http://www.gnu.org/licenses/>.\n',...
                '\n',...
                '\n'];
            appTextDlg(app, Message, 'About Us', 'none')
            
        end

        % Button pushed function: but_CometAdd
        function but_CometAdd_Callback(app, event)
            addComet(app)
            if ~isempty(app.classBrowser_handle)
                app.classBrowser_handle.recalcCatalog(app);
                app.classBrowser_handle.refreshCatalogPlot;
            end
        end

        % Button pushed function: but_CometRemove
        function but_CometRemove_Callback(app, event)
            removeComet(app)
            if ~isempty(app.classBrowser_handle)
                app.classBrowser_handle.recalcCatalog(app);
                app.classBrowser_handle.refreshCatalogPlot;
            end
        end

        % Button pushed function: but_Left
        function but_Left_Callback(app, event)
            
            app.scope.ImageSource = app.CometIcon;
            app.selectedComet = [];
            app.comet_handles.ROIshown = 0;
            app.comet_handles.ROIori = [];
            app.comet_handles.ROIoriFiltered = [];
            app.comet_handles.ROIsegm = [];
            app.comet_handles.ROI_ULCyx_DRCyx = [];
            
            NumImages = app.comet_handles.NumImages;
            if isempty(NumImages) || NumImages == 0
                return
            end
            Imgs_Composed = app.comet_handles.Imgs_Composed;
            IndImgShown = app.comet_handles.IndImgShown;
            
            if IndImgShown == 1
                IndImgShown = NumImages;
            else
                IndImgShown = IndImgShown - 1;
            end
            
            app.comet_handles.IndImgShown = IndImgShown;
            set(app.text_Num,'Text', ['Image: ' num2str(IndImgShown) '/' num2str(NumImages)]);
            set(app.text_Name,'Text', ['Image: ' char(app.comet_handles.ImgsNames{IndImgShown})]);
            imshow(uint8(Imgs_Composed(:,:,1:3,IndImgShown)), [], 'Parent', app.axes1);
            resetAx1(app)
        end

        % Button pushed function: but_Right
        function but_Right_Callback(app, event)
            
            app.scope.ImageSource = app.CometIcon;
            app.selectedComet = [];
            app.comet_handles.ROIshown = 0;
            app.comet_handles.ROIori = [];
            app.comet_handles.ROIoriFiltered = [];
            app.comet_handles.ROIsegm = [];
            app.comet_handles.ROI_ULCyx_DRCyx = [];
            
            NumImages = app.comet_handles.NumImages;
            if isempty(NumImages) || NumImages == 0
                return
            end
            Imgs_Composed = app.comet_handles.Imgs_Composed;
            IndImgShown = app.comet_handles.IndImgShown;
            
            if IndImgShown == NumImages
                IndImgShown = 1;
            else
                IndImgShown = IndImgShown + 1;
            end
            
            app.comet_handles.IndImgShown = IndImgShown;
            set(app.text_Num,'Text', ['Image: ' num2str(IndImgShown) '/' num2str(NumImages)]);
            set(app.text_Name,'Text', ['Image: ' char(app.comet_handles.ImgsNames{IndImgShown})]);
            imshow(uint8(Imgs_Composed(:,:,1:3,IndImgShown)), [], 'Parent', app.axes1);
            resetAx1(app)
        end

        % Button pushed function: but_SelectComet
        function but_SelectComet_Callback(app, event)
            if app.comet_handles.SelectACometOngoing == 1 || isempty(app.comet_handles.NumImages)
                return
            end
            
            app.selectedComet = [];
            
            app.scope.ImageSource = app.CometIcon;
            app.selectedComet = [];
            app.comet_handles.ROIshown = 0;
            app.comet_handles.ROIori = [];
            app.comet_handles.ROIoriFiltered = [];
            app.comet_handles.ROIsegm = [];
            app.comet_handles.MaskComet = [];
            app.comet_handles.MaskHead = [];
            MaskHead = [];
            app.comet_handles.ROI_ULCyx_DRCyx = [];
            
            NumImages = app.comet_handles.NumImages;
            if isempty(NumImages) || NumImages == 0
                return
            end
            
            Imgs_Stretched = app.comet_handles.Imgs_Stretched;
            IndImgShown = app.comet_handles.IndImgShown;
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            ImgShown = Imgs_Stretched(:,:,1,IndImgShown);
            [yrowOri, xcolOri, ~, ~] = size(ImgShown);
            app.comet_handles.CurrentCometHead_YrowXcol = [];
            
            %To avoid two segmentation in the same moment
            app.comet_handles.SelectACometOngoing = 1;
            
            % Manual selection with freehand selection
            try
                
                % New version: multiple-selection
                % New version from: http://stackoverflow.com/questions/23463516/draw-multiple-regions-on-an-image-imfreehand
                clear BWout1
                
                % To stop disable the GUI
                InterfaceObj=findobj(app.figure1,'Enable','on');
                set(InterfaceObj,'Enable','off');
                set(app.Panel,'Enable','on');
                set(app.uipanel1,'Enable','on');
                if strcmp(app.Menu_Annotation_Standard.Checked, 'on')
                    hFigFree2 = drawfreehand(app.axes1);
                elseif strcmp(app.Menu_Annotation_Assisted.Checked, 'on')
                    hFigFree2 = drawassisted(app.axes1);
                end
                % If a right ROI has been selected, this gives the opportunity to move the ROI
                wait( hFigFree2 );
                % Check on the minimum number of pixels of the ROI selected
                if ~isvalid(hFigFree2) || numel(find(createMask(hFigFree2)==1))<=app.comet_handles.ROIminNumPixels
                    app.comet_handles.SelectACometOngoing = 0;
                    app.comet_handles.ROIshown = 0;
                    app.comet_handles.ROIori = [];
                    app.comet_handles.ROIoriFiltered = [];
                    app.comet_handles.ROIsegm = [];
                    app.comet_handles.MaskComet = [];
                    app.comet_handles.MaskHead = [];
                    app.comet_handles.ROI_ULCyx_DRCyx = [];
                    if exist('hFigFree2', 'var'); delete(hFigFree2); end
                    errorString = 'No comet has been selected.';
                    appTextDlg(app, errorString, 'Message', 'warn')
                    set(InterfaceObj,'Enable','on');
                    return
                end
                
                if isvalid(hFigFree2)
                    PartialMask = createMask( hFigFree2 );
                else
                    PartialMask=0;
                end
                BWout1 = zeros(yrowOri, xcolOri);
                BWout1(PartialMask) = 1;
                
                clear pos
                pos = hFigFree2.Position;
                ULC_Yrow_roi = round(min([pos(:,2); yrowOri]));
                ULC_Xcol_roi = round(min([pos(:,1); xcolOri]));
                DRC_Yrow_roi = round(max([pos(:,2); 1]));
                DRC_Xcol_roi = round(max([pos(:,1); 1]));
                
                
                ROIori = ImgShown(ULC_Yrow_roi:DRC_Yrow_roi, ULC_Xcol_roi:DRC_Xcol_roi);
                if all(ROIori(:)==0) || all(ROIori(:)==255)
                    app.comet_handles.SelectACometOngoing = 0;
                    app.comet_handles.ROIshown = 0;
                    app.comet_handles.ROIori = [];
                    app.comet_handles.ROIoriFiltered = [];
                    app.comet_handles.ROIsegm = [];
                    app.comet_handles.MaskComet = [];
                    app.comet_handles.MaskHead = [];
                    app.comet_handles.ROI_ULCyx_DRCyx = [];
                    if exist('hFigFree2', 'var'); delete(hFigFree2); end
                    errorString = 'No comet has been detected.';
                    appTextDlg(app, errorString, 'Message', 'warn')
                    set(InterfaceObj,'Enable','on');
                    return
                end
                
                ImgShownFiltered = medfilt2(ImgShown, [5, 5], 'symmetric');
                h = fspecial('average', 3);
                ImgShownFiltered = imfilter(ImgShownFiltered, h, 'symmetric');
                ROIoriFiltered = ImgShownFiltered(ULC_Yrow_roi:DRC_Yrow_roi, ULC_Xcol_roi:DRC_Xcol_roi);
                
                ROIsegm = BWout1(ULC_Yrow_roi:DRC_Yrow_roi, ULC_Xcol_roi:DRC_Xcol_roi);
                
                % Delete line
                if exist('hFigFree2', 'var'); delete(hFigFree2); end
                
                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end
                
                % If comet head present (3 = Present tail and head)
                if flag_CurrentCometType == 3
                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);
                end
                
                % To delete external pixels in case of perfect fit.
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end
                
                classLayer = app.comet_handles.Imgs_Composed(ULC_Yrow_roi:DRC_Yrow_roi, ULC_Xcol_roi:DRC_Xcol_roi, 4, IndImgShown);
                classIdx = unique(classLayer(logical(MaskComet)));
                classIdx = classIdx(classIdx>0);
                
                if length(classIdx) == 1
                    classNames = fieldnames(app.comet_handles.Classes);
                    [maskRow, maskCol] = find(MaskComet==1);
                    maskCenterCoor = round([((min(maskRow) + max(maskRow))/2)+ULC_Yrow_roi, ((min(maskCol) + max(maskCol))/2)+ULC_Xcol_roi]);
                    membersOnThisImage = app.comet_handles.Classes.(classNames{classIdx}).Members([app.comet_handles.Classes.(classNames{classIdx}).Members.ImID] == IndImgShown);
                    idToShow = [];
                    for thumbIterator = 1:size(membersOnThisImage,2)
                        currentThumbcoor = membersOnThisImage(thumbIterator).thumbnailCoor;
                        if currentThumbcoor(1, 1) < maskCenterCoor(1) && currentThumbcoor(2, 1) > maskCenterCoor(1) &&...
                                currentThumbcoor(2, 2) < maskCenterCoor(2) && currentThumbcoor(1, 2) > maskCenterCoor(2)
                        if isempty(idToShow)
                            idToShow = thumbIterator;
                        else
                            errorString = 'Selected coordinates have been found stored as coordinates of multipe class members!\n Please contact the developer!';
                            appTextDlg(app, errorString, 'Corrupted Class structure', 'error')
                            set(InterfaceObj,'Enable','on');
                            app.comet_handles.SelectACometOngoing = 0;
                            return
                        end
                        end
                    end
                    if ~isempty(idToShow)
                        coorToShow = membersOnThisImage(idToShow).thumbnailCoor;
                        ROIcomposed = app.comet_handles.Imgs_Composed(coorToShow(1,1):coorToShow(2,1),coorToShow(2,2):coorToShow(1,2),1:3,IndImgShown);
                        app.selectedComet.className = classNames{classIdx};
                        app.selectedComet.param = membersOnThisImage(idToShow);
                    else
                        errorString = 'Can not detect any class, but should!\n Please contact the developer!';
                        appTextDlg(app, errorString, 'Corrupted Class structure', 'error')
                        set(InterfaceObj,'Enable','on');
                        app.comet_handles.SelectACometOngoing = 0;
                        return
                    end
                    
                elseif isempty(classIdx)
                    ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                    app.comet_handles.ROIshown = 1;
                    app.comet_handles.ROIori = ROIori;
                    app.comet_handles.ROIoriFiltered = ROIoriFiltered;
                    app.comet_handles.ROIsegm = ROIsegm;
                    app.comet_handles.MaskHead = MaskHead;
                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.ROI_ULCyx_DRCyx = [ULC_Yrow_roi, ULC_Xcol_roi, DRC_Yrow_roi, DRC_Xcol_roi];
                else
                    errorString = 'Multiple class IDs have been found in the selected region.\n Please contact the developer!';
                    appTextDlg(app, errorString, 'Corrupted Class structure', 'error')
                    set(InterfaceObj,'Enable','on');
                    app.comet_handles.SelectACometOngoing = 0;
                    return
                end
                
                
                app.scope.ImageSource = uint8(ROIcomposed);


                app.comet_handles.SelectACometOngoing = 0;
                set(InterfaceObj,'Enable','on');
            catch ME
                
                app.scope.ImageSource = app.CometIcon;
                app.selectedComet = [];
                app.comet_handles.SelectACometOngoing = 0;
                app.comet_handles.ROIshown = 0;
                app.comet_handles.ROIori = [];
                app.comet_handles.ROIoriFiltered = [];
                app.comet_handles.ROIsegm = [];
                app.comet_handles.MaskComet = [];
                app.comet_handles.MaskHead = [];
                app.comet_handles.ROI_ULCyx_DRCyx = [];
                if exist('hFigFree2', 'var'); delete(hFigFree2); end
                errorString = ['Wrong segmentation. \n\nError description:\n' ME.message];
                appTextDlg(app, errorString, 'Error', 'error')
                set(InterfaceObj,'Enable','on');
            end
        end

        % Button pushed function: but_ShowOriImg
        function but_ShowOriImg_Callback(app, event)
            if app.comet_handles.SelectACometOngoing == 1
                return
            end
            
            if ishandle(app.comet_handles.h_GUIhelpA); close(app.comet_handles.h_GUIhelpA); end
            
            IndImgShown = app.comet_handles.IndImgShown;
            dirList = app.comet_handles.dirList;
            PathInputFolder = app.comet_handles.PathInputFolderOriginal;
            
            if ~isempty(PathInputFolder) && all(PathInputFolder~=0)
                try
                    referenceFrame = imread([PathInputFolder char(dirList(IndImgShown).name)]);
                    newFig = figure;
                    newAx = axes(newFig);
                    imshow(referenceFrame, 'Parent', newAx, 'Border', 'Tight')
                catch
                    try close(BarWaitWindows); catch; end
                    errorString = 'Impossible to open the original image.';
                    appTextDlg(app, errorString, 'Error', 'error')
                end
            else
                try close(BarWaitWindows); catch; end
                errorString = 'Impossible to open the original images of a loaded project.';
                appTextDlg(app, errorString, 'Error', 'error')
            end
        end

        % Button pushed function: but_diHead_less
        function but_diHead_less_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                HeadSizeDiskDilation = app.comet_handles.HeadSizeDiskDilation;
                HeadSizeDiskDilation = HeadSizeDiskDilation - 1;
                if HeadSizeDiskDilation >= 0
                    app.comet_handles.HeadSizeDiskDilation = HeadSizeDiskDilation;
                    set(app.text_DilationHead,'Text', num2str(HeadSizeDiskDilation));
                    
                    % Set slider
                    sliderValue_0_1 = HeadSizeDiskDilation/150;
                    set(app.slider_head_Dilation,'Value', sliderValue_0_1);
                    
                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                    
                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);
                    
                    % To delete external pixels in case of perfect fit.
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end
                    
                    ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                    
                    app.scope.ImageSource = uint8(ROIcomposed);
                    
                    
                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            end
        end

        % Button pushed function: but_diHead_plus
        function but_diHead_plus_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                HeadSizeDiskDilation = app.comet_handles.HeadSizeDiskDilation;
                HeadSizeDiskDilation = HeadSizeDiskDilation + 1;
                if HeadSizeDiskDilation >= 0
                    app.comet_handles.HeadSizeDiskDilation = HeadSizeDiskDilation;
                    set(app.text_DilationHead,'Text', num2str(HeadSizeDiskDilation));
                    
                    % Set slider
                    sliderValue_0_1 = HeadSizeDiskDilation/150;
                    set(app.slider_head_Dilation,'Value', sliderValue_0_1);
                    
                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                    
                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);
                    
                    % To delete external pixels in case of perfect fit.
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end
                    
                    ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                    
                    app.scope.ImageSource = uint8(ROIcomposed);
                    
                    
                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            end
        end

        % Button pushed function: but_di_less
        function but_di_less_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                CometSizeDiskDilation = app.comet_handles.CometSizeDiskDilation;
                CometSizeDiskDilation = CometSizeDiskDilation - 1;
                if CometSizeDiskDilation >= 0
                    app.comet_handles.CometSizeDiskDilation = CometSizeDiskDilation;
                    set(app.text_Dilation,'Text', num2str(CometSizeDiskDilation));
                    
                    % Set slider
                    sliderValue_0_1 = CometSizeDiskDilation/150;
                    set(app.slider_comet_Dilation,'Value', sliderValue_0_1);
                    
                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                    
                    % Segment comet
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    if flag_CometFitFreehand == 1
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                    else
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                    end
                    if flag_CurrentCometType == 1
                        MaskHead = MaskComet;
                    end
                    
                    % To delete external pixels in case of perfect fit.
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end
                    
                    ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                    
                    app.scope.ImageSource = uint8(ROIcomposed);
                    
                    
                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            end
        end

        % Button pushed function: but_di_plus
        function but_di_plus_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                CometSizeDiskDilation = app.comet_handles.CometSizeDiskDilation;
                CometSizeDiskDilation = CometSizeDiskDilation + 1;
                if CometSizeDiskDilation >= 0
                    app.comet_handles.CometSizeDiskDilation = CometSizeDiskDilation;
                    set(app.text_Dilation,'Text', num2str(CometSizeDiskDilation));
                    
                    % Set slider
                    sliderValue_0_1 = CometSizeDiskDilation/150;
                    set(app.slider_comet_Dilation,'Value', sliderValue_0_1);
                    
                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                    
                    % Segment comet
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    if flag_CometFitFreehand == 1
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                    else
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                    end
                    if flag_CurrentCometType == 1
                        MaskHead = MaskComet;
                    end
                    
                    % To delete external pixels in case of perfect fit.
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end
                    
                    ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                    
                    app.scope.ImageSource = uint8(ROIcomposed);
                    
                    
                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            end
        end

        % Button pushed function: but_thHead_less
        function but_thHead_less_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                HeadThAddFactor = app.comet_handles.HeadThAddFactor;
                HeadThAddFactor = HeadThAddFactor - 1;
                app.comet_handles.HeadThAddFactor = HeadThAddFactor;
                set(app.text_ThresholdHead,'Text', num2str(HeadThAddFactor));
                
                % Set slider
                sliderValue_0_1 = 0.5 + HeadThAddFactor/(255*2);
                set(app.slider_head_threshold,'Value', sliderValue_0_1);
                
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                
                [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);
                
                % To delete external pixels in case of perfect fit.
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end
                
                ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                
                app.scope.ImageSource = uint8(ROIcomposed);
                
                
                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Button pushed function: but_thHead_plus
        function but_thHead_plus_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                HeadThAddFactor = app.comet_handles.HeadThAddFactor;
                HeadThAddFactor = HeadThAddFactor + 1;
                app.comet_handles.HeadThAddFactor = HeadThAddFactor;
                set(app.text_ThresholdHead,'Text', num2str(HeadThAddFactor));
                
                % Set slider
                sliderValue_0_1 = 0.5 + HeadThAddFactor/(255*2);
                set(app.slider_head_threshold,'Value', sliderValue_0_1);
                
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                
                [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);
                
                % To delete external pixels in case of perfect fit.
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end
                
                ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                
                app.scope.ImageSource = uint8(ROIcomposed);
                
                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Button pushed function: but_th_less
        function but_th_less_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                CometThAddFactor = app.comet_handles.CometThAddFactor;
                CometThAddFactor = CometThAddFactor - 1;
                app.comet_handles.CometThAddFactor = CometThAddFactor;
                set(app.text_Threshold,'Text', num2str(CometThAddFactor));
                
                % Set slider
                sliderValue_0_1 = 0.5 + CometThAddFactor/(255*2);
                set(app.slider_comet_threshold,'Value', sliderValue_0_1);
                
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                
                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end
                
                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end
                
                ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                
                app.scope.ImageSource = uint8(ROIcomposed);
                
                
                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Button pushed function: but_th_plus
        function but_th_plus_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                CometThAddFactor = app.comet_handles.CometThAddFactor;
                CometThAddFactor = CometThAddFactor + 1;
                app.comet_handles.CometThAddFactor = CometThAddFactor;
                set(app.text_Threshold,'Text', num2str(CometThAddFactor));
                
                % Set slider
                sliderValue_0_1 = 0.5 + CometThAddFactor/(255*2);
                set(app.slider_comet_threshold,'Value', sliderValue_0_1);
                
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                
                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end
                
                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end
                
                ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                
                app.scope.ImageSource = uint8(ROIcomposed);
                
                
                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Value changed function: check_CometFitFreehand
        function check_CometFitFreehand_Callback(app, event)
            if app.comet_handles.SelectACometOngoing == 1 || app.comet_handles.LoadImagesOngoing == 1
                set(app.check_CometFitFreehand,'Value',app.comet_handles.flag_CometFitFreehand);
                return
            end
            
            flag_CometFitFreehand = get(app.check_CometFitFreehand,'Value');
            app.comet_handles.flag_CometFitFreehand = flag_CometFitFreehand;
            
            if app.comet_handles.ROIshown == 1
                MaskHead = [];
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                
                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if app.comet_handles.flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end
                
                % If comet head present (3 = Present tail and head)
                if app.comet_handles.flag_CurrentCometType == 3
                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation);
                end
                
                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end
                
                ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, app.comet_handles.flag_CurrentCometType);
                
                app.scope.ImageSource = uint8(ROIcomposed);
                
                
                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Value changed function: pop_class
        function pop_class_Callback(app, event)
            app.comet_handles.ClassID = app.comet_handles.Classes.(app.pop_class.Value).ID;
        end

        % Value changed function: pop_tail
        function pop_tail_Callback(app, event)
            if app.comet_handles.SelectACometOngoing == 1
                set(app.pop_tail,'Value',app.pop_tail.Items(app.comet_handles.flag_CurrentCometType));
                return
            end
            
            
            app.scope.ImageSource = app.CometIcon;
            app.selectedComet = [];
            
            app.comet_handles.ROIshown = 0;
            app.comet_handles.ROIori = [];
            app.comet_handles.ROIoriFiltered = [];
            app.comet_handles.ROIsegm = [];
            app.comet_handles.MaskComet = [];
            app.comet_handles.MaskHead = [];
            app.comet_handles.ROI_ULCyx_DRCyx = [];
            
            %1 = No comet tail
            %2 = No comet head
            %3 = Present tail and head
            app.comet_handles.flag_CurrentCometType = find(strcmp(app.pop_tail.Items, app.pop_tail.Value));
        end

        % Value changed function: pop_thresholdHead
        function pop_thresholdHead_Callback(app, event)
            %1 = Otsu
            %2 = Triangle
            %3 = Average Otsu and Triangle
            app.comet_handles.flag_ThresholdHeadMode = find(strcmp(app.pop_thresholdHead.Items, app.pop_thresholdHead.Value));
            
            if app.comet_handles.ROIshown == 1
                MaskHead = [];
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                
                flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
                
                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end
                
                % If comet head present (3 = Present tail and head)
                if flag_CurrentCometType == 3
                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);
                end
                
                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end
                
                ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                
                app.scope.ImageSource = uint8(ROIcomposed);
                
                
                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Value changed function: pop_threshold
        function pop_threshold_Callback(app, event)
            %1 = Otsu
            %2 = Triangle
            %3 = Average Otsu and Triangle
            app.comet_handles.flag_ThresholdMode = find(strcmp(app.pop_threshold.Items, app.pop_threshold.Value));
            
            if app.comet_handles.ROIshown == 1
                MaskHead = [];
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                
                flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
                
                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end
                
                % If comet head present (3 = Present tail and head)
                if flag_CurrentCometType == 3
                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);
                end
                
                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end
                
                ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                
                app.scope.ImageSource = uint8(ROIcomposed);
                
                
                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Value changed function: slider_comet_Dilation
        function slider_comet_Dilation_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                
                % Read slider
                sliderValue_0_1 = get(app.slider_comet_Dilation,'Value');
                CometSizeDiskDilation = round((sliderValue_0_1)*150); % Max dilation imposed at 150 pixels
                
                if CometSizeDiskDilation >= 0
                    app.comet_handles.CometSizeDiskDilation = CometSizeDiskDilation;
                    set(app.text_Dilation,'Text', num2str(CometSizeDiskDilation));
                    
                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                    
                    % Segment comet
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    if flag_CometFitFreehand == 1
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                    else
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                    end
                    if flag_CurrentCometType == 1
                        MaskHead = MaskComet;
                    end
                    
                    % To delete external pixels in case of perfect fit.
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end
                    
                    ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                    
                    app.scope.ImageSource = uint8(ROIcomposed);
                    
                    
                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            else
                % Set slider
                CometSizeDiskDilation = app.comet_handles.CometSizeDiskDilation;
                sliderValue_0_1 = CometSizeDiskDilation/150;
                set(app.slider_comet_Dilation,'Value', sliderValue_0_1);
            end
        end

        % Value changed function: slider_comet_threshold
        function slider_comet_threshold_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                
                % Read slider
                sliderValue_0_1 = get(app.slider_comet_threshold,'Value');
                CometThAddFactor = round((sliderValue_0_1-0.5)*(255*2));
                app.comet_handles.CometThAddFactor = CometThAddFactor;
                
                set(app.text_Threshold,'Text', num2str(CometThAddFactor));
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                
                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end
                
                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end
                
                ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                
                app.scope.ImageSource = uint8(ROIcomposed);
                
                
                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            else
                % Set slider
                CometThAddFactor = app.comet_handles.CometThAddFactor;
                sliderValue_0_1 = 0.5 + CometThAddFactor/(255*2);
                set(app.slider_comet_threshold,'Value', sliderValue_0_1);
            end
        end

        % Value changed function: slider_head_Dilation
        function slider_head_Dilation_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                
                % Read slider
                sliderValue_0_1 = get(app.slider_head_Dilation,'Value');
                HeadSizeDiskDilation = round((sliderValue_0_1)*150); % Max dilation imposed at 150 pixels
                
                if HeadSizeDiskDilation >= 0
                    app.comet_handles.HeadSizeDiskDilation = HeadSizeDiskDilation;
                    set(app.text_DilationHead,'Text', num2str(HeadSizeDiskDilation));
                    
                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                    
                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);
                    
                    % To delete external pixels in case of perfect fit.
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end
                    
                    ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                    
                    app.scope.ImageSource = uint8(ROIcomposed);
                    
                    
                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            else
                % Set slider
                HeadSizeDiskDilation = app.comet_handles.HeadSizeDiskDilation;
                sliderValue_0_1 = HeadSizeDiskDilation/150;
                set(app.slider_head_Dilation,'Value', sliderValue_0_1);
            end
        end

        % Value changed function: slider_head_threshold
        function slider_head_threshold_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                
                % Read slider
                sliderValue_0_1 = get(app.slider_head_threshold,'Value');
                HeadThAddFactor = round((sliderValue_0_1-0.5)*(255*2));
                app.comet_handles.HeadThAddFactor = HeadThAddFactor;
                
                set(app.text_ThresholdHead,'Text', num2str(HeadThAddFactor));
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                
                [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);
                
                % To delete external pixels in case of perfect fit.
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end
                
                ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                
                app.scope.ImageSource = uint8(ROIcomposed);
                
                
                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            else
                % Set slider
                HeadThAddFactor = app.comet_handles.HeadThAddFactor;
                sliderValue_0_1 = 0.5 + HeadThAddFactor/(255*2);
                set(app.slider_head_threshold,'Value', sliderValue_0_1);
            end
        end

        % Button pushed function: text_DilationHead
        function text_DilationHead_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                HeadSizeDiskDilation = 0;
                if HeadSizeDiskDilation >= 0
                    app.comet_handles.HeadSizeDiskDilation = HeadSizeDiskDilation;
                    set(app.text_DilationHead,'Text', num2str(HeadSizeDiskDilation));
                    
                    % Set slider
                    sliderValue_0_1 = HeadSizeDiskDilation/150;
                    set(app.slider_head_Dilation,'Value', sliderValue_0_1);
                    
                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                    
                    [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);
                    
                    % To delete external pixels in case of perfect fit.
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end
                    
                    ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                    
                    app.scope.ImageSource = uint8(ROIcomposed);
                    
                    
                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            end
        end

        % Button pushed function: text_Dilation
        function text_Dilation_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                CometSizeDiskDilation = 0;
                if CometSizeDiskDilation >= 0
                    app.comet_handles.CometSizeDiskDilation = CometSizeDiskDilation;
                    set(app.text_Dilation,'Text', num2str(CometSizeDiskDilation));
                    
                    % Set slider
                    sliderValue_0_1 = CometSizeDiskDilation/150;
                    set(app.slider_comet_Dilation,'Value', sliderValue_0_1);
                    
                    ROIori = app.comet_handles.ROIori;
                    ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                    
                    % Segment comet
                    flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                    if flag_CometFitFreehand == 1
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                    else
                        [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                    end
                    if flag_CurrentCometType == 1
                        MaskHead = MaskComet;
                    end
                    
                    % To delete external pixels in case of perfect fit.
                    ROIsegm = app.comet_handles.ROIsegm;
                    if flag_CometFitFreehand == 1
                        if ~isempty(MaskHead)
                            MaskHead(ROIsegm==0)=0;
                        end
                        if ~isempty(MaskComet)
                            MaskComet(ROIsegm==0)=0;
                        end
                    end
                    
                    ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                    
                    app.scope.ImageSource = uint8(ROIcomposed);
                    
                    
                    app.comet_handles.MaskComet = MaskComet;
                    app.comet_handles.MaskHead = MaskHead;
                end
            end
        end

        % Button pushed function: text_ThresholdHead
        function text_ThresholdHead_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType) && flag_CurrentCometType==3
                MaskComet = app.comet_handles.MaskComet;
                HeadThAddFactor = 0;
                app.comet_handles.HeadThAddFactor = HeadThAddFactor;
                set(app.text_ThresholdHead,'Text', num2str(HeadThAddFactor));
                
                % Set slider
                sliderValue_0_1 = 0.5 + HeadThAddFactor/(255*2);
                set(app.slider_head_threshold,'Value', sliderValue_0_1);
                
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                
                [MaskHead, ~] = segmentHead(ROIoriFiltered, MaskComet, app.comet_handles.HeadThAddFactor, app.comet_handles.HeadSizeDiskDilation, app.comet_handles.flag_ThresholdHeadMode);
                
                % To delete external pixels in case of perfect fit.
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end
                
                ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                
                app.scope.ImageSource = uint8(ROIcomposed);
                
                
                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Button pushed function: text_Threshold
        function text_Threshold_Callback(app, event)
            flag_CurrentCometType = app.comet_handles.flag_CurrentCometType;
            
            if app.comet_handles.ROIshown == 1 && ~isempty(flag_CurrentCometType)
                MaskHead = app.comet_handles.MaskHead;
                CometThAddFactor = 0;
                app.comet_handles.CometThAddFactor = CometThAddFactor;
                set(app.text_Threshold,'Text', num2str(CometThAddFactor));
                
                % Set slider
                sliderValue_0_1 = 0.5 + CometThAddFactor/(255*2);
                set(app.slider_comet_threshold,'Value', sliderValue_0_1);
                
                ROIori = app.comet_handles.ROIori;
                ROIoriFiltered = app.comet_handles.ROIoriFiltered;
                
                % Segment comet
                flag_CometFitFreehand = app.comet_handles.flag_CometFitFreehand;
                if flag_CometFitFreehand == 1
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, 0, 0, 0);
                else
                    [MaskComet, ~] = segmentComet(ROIoriFiltered, app.comet_handles.ROIsegm, app.comet_handles.CometThAddFactor, app.comet_handles.CometSizeDiskDilation, app.comet_handles.flag_ThresholdMode);
                end
                if flag_CurrentCometType == 1
                    MaskHead = MaskComet;
                end
                
                % To delete external pixels in case of perfect fit.
                ROIsegm = app.comet_handles.ROIsegm;
                if flag_CometFitFreehand == 1
                    if ~isempty(MaskHead)
                        MaskHead(ROIsegm==0)=0;
                    end
                    if ~isempty(MaskComet)
                        MaskComet(ROIsegm==0)=0;
                    end
                end
                
                ROIcomposed = falseColorsComet(ROIori, MaskHead, MaskComet, flag_CurrentCometType);
                
                app.scope.ImageSource = uint8(ROIcomposed);
                
                
                app.comet_handles.MaskComet = MaskComet;
                app.comet_handles.MaskHead = MaskHead;
            end
        end

        % Key press function: figure1
        function KeyPressFcn(app, event)
            key = event.Key;
            
            switch key
                case 'q'
                    InterfaceObj=findobj(app.figure1,'Enable','off');
                    set(InterfaceObj, 'Enable', 'on');
                    app.calculating = 0;
                    
                case 'y'
                    pause(1)
                    %                     if app.comet_handles.ClassCounter > 0
                    %                         answ = questdlg('Do you want to save training data for YOLO training?','YOLO trSet export',{'Yes','No','No'});
                    %                         if strcmp(answ,'Yes')
                    %                             indx = listdlg('PromptString', 'Select class to save', 'ListString', app.pop_class.Items, 'SelectionMode', 'single');
                    %                             imFolderPath = app.comet_handles.dirList(1).folder;
                    %                             calcYoloInputCoordinates(app.comet_handles.Classes.(app.pop_class.Items{indx}).Members, imFolderPath)
                    %                         end
                    %                     end
                otherwise
            end
        end

        % Menu selected function: Menu_Annotation_Standard
        function Menu_Annotation_StandardSelected(app, event)
            event.Source.Checked = 'on';
            app.Menu_Annotation_Assisted.Checked = 'off';
        end

        % Menu selected function: Menu_Annotation_Assisted
        function Menu_Annotation_AssistedSelected(app, event)
            event.Source.Checked = 'on';
            app.Menu_Annotation_Standard.Checked = 'off';
        end

        % Button pushed function: ClassBrowserButton
        function ClassBrowserButtonPushed(app, event)
            % Calc snapshots
            Classes = app.comet_handles.Classes;
            if ~isempty(Classes)
                app.scope.ImageSource = app.CometIcon;
                if isempty(app.classBrowser_handle)
                    classIdx = fieldnames(Classes);
                    for cl = 1:numel(classIdx)
                        for th = 1:Classes.(classIdx{cl}).num_el
                            coor = Classes.(classIdx{cl}).Members(th).thumbnailCoor;
                            imID = Classes.(classIdx{cl}).Members(th).ImID;
                            Classes.(classIdx{cl}).Members(th).Thumbnail = app.comet_handles.Imgs_Stretched(coor(1,1):coor(2,1),coor(2,2):coor(1,2),1,imID);
                        end
                    end
                    
                    app.classBrowser_handle = classBrowser(app);
                else
                    app.classBrowser_handle.Class_Browser.Visible = 'off';
                    app.classBrowser_handle.Class_Browser.Visible = 'on';
                end
            end
        end

        % Window button motion function: figure1
        function figure1WindowButtonMotion(app, event)
            if app.comet_handles.IndImgShown
                coor = round(app.axes1.CurrentPoint(1,1:2));
                if coor(1) > 0 && coor(1) < app.comet_handles.ImageSize(2) && ...
                        coor(2) > 0 && coor(2) < app.comet_handles.ImageSize(1)
                if app.comet_handles.Imgs_Composed(coor(2),coor(1),4,app.comet_handles.IndImgShown)
                    classIdx = app.comet_handles.Imgs_Composed(coor(2),coor(1),4,app.comet_handles.IndImgShown);
                    if classIdx < 254
                        classNames = fieldnames(app.comet_handles.Classes);
                        classNameIdx = cellfun(@(s) ismember(classIdx, app.comet_handles.Classes.(s).ID),classNames);
                        selectedName = classNames{classNameIdx};
                    else
                        selectedName = 'Unclassified';
                    end
                    if isempty(app.imDatatipText)
                        if coor(1) > app.comet_handles.ImageSize(2)/2
                            xSpecing = -20 - 12 * length(selectedName);
                        else
                            xSpecing = 30;
                        end
                        if coor(2) > app.comet_handles.ImageSize(1)/2
                            ySpecing = -30;
                        else
                            ySpecing = 30;
                        end
                        app.imDatatipText = text(app.axes1,coor(1)+xSpecing,coor(2)+ySpecing,selectedName,...
                            'Color','red','FontSize',14, 'FontWeight', 'bold', 'Interpreter', 'none');
                        app.imDatatipText.BackgroundColor = [1 1 1];
                    end
                else
                    delete(app.imDatatipText)
                    app.imDatatipText = [];
                end
                end
            end
        end

        % Window button down function: figure1
        function figure1WindowButtonDown(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                IndImgShown = app.comet_handles.IndImgShown;
                coor = round(app.axes1.CurrentPoint(1,1:2));
                if coor(1) > 0 && coor(1) < app.comet_handles.ImageSize(2) && ...
                        coor(2) > 0 && coor(2) < app.comet_handles.ImageSize(1)
                if app.comet_handles.Imgs_Composed(coor(2),coor(1),4,app.comet_handles.IndImgShown)
                    if app.comet_handles.ROIshown
                        app.comet_handles.SelectACometOngoing = 0;
                        app.comet_handles.ROIshown = 0;
                        app.comet_handles.ROIori = [];
                        app.comet_handles.ROIoriFiltered = [];
                        app.comet_handles.ROIsegm = [];
                        app.comet_handles.MaskComet = [];
                        app.comet_handles.MaskHead = [];
                        app.comet_handles.ROI_ULCyx_DRCyx = [];
                    end
                    classIdx = app.comet_handles.Imgs_Composed(coor(2),coor(1),4,app.comet_handles.IndImgShown);
                    classNames = fieldnames(app.comet_handles.Classes);
                    membersOnThisImage = app.comet_handles.Classes.(classNames{classIdx}).Members([app.comet_handles.Classes.(classNames{classIdx}).Members.ImID] == IndImgShown);
                    idToShow = [];
                    for thumbIterator = 1:size(membersOnThisImage,2)
                        currentThumbcoor = membersOnThisImage(thumbIterator).thumbnailCoor;
                        if currentThumbcoor(1, 1) < coor(2) && currentThumbcoor(2, 1) > coor(2) &&...
                                currentThumbcoor(2, 2) < coor(1) && currentThumbcoor(1, 2) > coor(1)
                        if isempty(idToShow)
                            idToShow = thumbIterator;
                        else
                            errorString = 'Selected coordinates have been found stored as coordinates of multipe class members!\n Please contact the developer!';
                            appTextDlg(app, errorString, 'Corrupted Class structure', 'error')
                        end
                        end
                    end
                    if ~isempty(idToShow)
                        coorToShow = membersOnThisImage(idToShow).thumbnailCoor;
                        app.comet_handles.ROIshown = 1;
                        ROIcomposed = app.comet_handles.Imgs_Composed(coorToShow(1,1):coorToShow(2,1),coorToShow(2,2):coorToShow(1,2),1:3,IndImgShown);
                        
                        app.scope.ImageSource = uint8(ROIcomposed);
                        
                        app.selectedComet.className = classNames{classIdx};
                        app.selectedComet.param = membersOnThisImage(idToShow);
                    end
                else
                    app.selectedComet = [];
                    
                    app.scope.ImageSource = app.CometIcon;
                    app.selectedComet = [];
                    app.comet_handles.ROIshown = 0;
                    app.comet_handles.ROIori = [];
                    app.comet_handles.ROIoriFiltered = [];
                    app.comet_handles.ROIsegm = [];
                    app.comet_handles.MaskComet = [];
                    app.comet_handles.MaskHead = [];
                    app.comet_handles.ROI_ULCyx_DRCyx = [];
                end
                end
                app.calculating = 0;
            end
        end

        % Button pushed function: Class_Create_Button
        function Class_Create_ButtonPushed(app, event)
            if ~isempty(app.comet_handles.NumImages) && app.comet_handles.NumImages
                classNameInput(app);
                pop_class_Callback(app)
                if app.classCreationSuccessor && ~isempty(app.classBrowser_handle)
                    app.classBrowser_handle.addClassToCatalog(app.pop_class.Value)
                end
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            if ~isempty(app.classBrowser_handle)
                close(app.classBrowser_handle.Class_Browser)
            end
            delete(app)
        end

        % Button pushed function: Class_Remove_Button
        function Class_Remove_ButtonPushed(app, event)
            Classes = app.comet_handles.Classes;
            if ~isempty(Classes)
                answer = questdlg('Do you want to delete the selected class?','Remove Class','Yes','No','No');
                if ~isempty(answer) && strcmp(answer,'Yes')
                    numOfMembers = app.comet_handles.Classes.(app.pop_class.Value).num_el;
                    if numOfMembers > 0
                        app.selectedComet.className = app.pop_class.Value;
                        for i = 1:numOfMembers
                            app.selectedComet.param = app.comet_handles.Classes.(app.selectedComet.className).Members(numOfMembers - i + 1);
                            app.but_CometRemove_Callback(app);
                        end
                        app.selectedComet = [];
                    end
                    app.comet_handles.Classes = rmfield(app.comet_handles.Classes,app.pop_class.Value);
                    if length(app.pop_class.Items) == 1
                        app.pop_class.Items = {'~No Class~'};
                        app.pop_class.Value = '~No Class~';
                        app.comet_handles.ClassID = 0;
                        app.comet_handles.ClassCounter = 0;
                        if ~isempty(app.classBrowser_handle)
                            close(app.classBrowser_handle.Class_Browser)
                        end
                    else
                        ID2delete = strcmp(app.pop_class.Items,app.pop_class.Value);
                        if ~isempty(app.classBrowser_handle)
                            app.classBrowser_handle.removeClassFromCatalog(app.pop_class.Value)
                        end
                        app.pop_class.Items(ID2delete) = [];
                        app.pop_class.Value = app.pop_class.Items{1};
                        app.comet_handles.ClassCounter = app.comet_handles.ClassCounter - 1;
                    end
                end
            end
            
        end

        % Menu selected function: ImageSegmentationMenu
        function ImageSegmentationMenuSelected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                predictImageSegmentation(app,'single')
                imshow(uint8(app.comet_handles.Imgs_Composed(:,:,1:3,app.comet_handles.IndImgShown)), [], 'Parent', app.axes1);
                resetAx1(app)
                app.calculating = 0;
            end
        end

        % Menu selected function: TrainNewModelMenu
        function TrainNewModelMenuSelected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                path = uigetdir('Select folder of annotated training data!');
                if ischar(path)
                    trainSemanticSegmentationModel(app, path)
                end
                app.calculating = 0;
            end
        end

        % Menu selected function: OptionsMenu
        function OptionsMenuSelected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                segmentationOptions(app)
                app.calculating = 0;
            end
        end

        % Menu selected function: ExportAnnotationsMenu
        function ExportAnnotationsMenuSelected(app, event)
            if ~isempty(app.comet_handles.IndImgShown) && app.comet_handles.IndImgShown && ~app.calculating && ~app.comet_handles.SelectACometOngoing
                app.calculating = 1;
                exportAnnotation(app, path)
                app.calculating = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CometAnalyser_GUI';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.WindowButtonDownFcn = createCallbackFcn(app, @figure1WindowButtonDown, true);
            app.figure1.WindowButtonMotionFcn = createCallbackFcn(app, @figure1WindowButtonMotion, true);
            app.figure1.KeyPressFcn = createCallbackFcn(app, @KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.Menu_File = uimenu(app.figure1);
            app.Menu_File.Text = 'File';
            app.Menu_File.Tag = 'Menu_File';
            app.Menu_File_OpenS = uimenu(app.Menu_File);
            app.Menu_File_OpenS.MenuSelectedFcn = createCallbackFcn(app, @Menu_File_OpenS_Callback, true);
            app.Menu_File_OpenS.Text = 'Open (silver-stained images)';
            app.Menu_File_OpenS.Tag = 'Menu_File_OpenS';
            app.Menu_File_OpenF = uimenu(app.Menu_File);
            app.Menu_File_OpenF.MenuSelectedFcn = createCallbackFcn(app, @Menu_File_OpenF_Callback, true);
            app.Menu_File_OpenF.Text = 'Open (fluorescence images)';
            app.Menu_File_OpenF.Tag = 'Menu_File_OpenF';
            app.Menu_File_LoadData = uimenu(app.Menu_File);
            app.Menu_File_LoadData.MenuSelectedFcn = createCallbackFcn(app, @Menu_File_LoadData_Callback, true);
            app.Menu_File_LoadData.Separator = 'on';
            app.Menu_File_LoadData.Text = 'Load project';
            app.Menu_File_LoadData.Tag = 'Menu_File_LoadData';
            app.Menu_File_SaveData = uimenu(app.Menu_File);
            app.Menu_File_SaveData.MenuSelectedFcn = createCallbackFcn(app, @Menu_File_SaveData_Callback, true);
            app.Menu_File_SaveData.Text = 'Save project';
            app.Menu_File_SaveData.Tag = 'Menu_File_SaveData';
            app.Menu_Analysis = uimenu(app.figure1);
            app.Menu_Analysis.Text = 'Analysis';
            app.Menu_Analysis.Tag = 'Menu_Analysis';
            app.Menu_Analysis_ExpMeasurements = uimenu(app.Menu_Analysis);
            app.Menu_Analysis_ExpMeasurements.MenuSelectedFcn = createCallbackFcn(app, @Menu_Analysis_ExpMeasurements_Callback, true);
            app.Menu_Analysis_ExpMeasurements.Text = 'Export measurements';
            app.Menu_Analysis_ExpMeasurements.Tag = 'Menu_Analysis_ExpMeasurements';
            app.Menu_Analysis_ExpSnapshots = uimenu(app.Menu_Analysis);
            app.Menu_Analysis_ExpSnapshots.MenuSelectedFcn = createCallbackFcn(app, @Menu_Analysis_ExpSnapshots_Callback, true);
            app.Menu_Analysis_ExpSnapshots.Text = 'Export comet snapshots';
            app.Menu_Analysis_ExpSnapshots.Tag = 'Menu_Analysis_ExpSnapshots';
            app.Menu_Annotation = uimenu(app.figure1);
            app.Menu_Annotation.Text = 'Annotation';
            app.Menu_Annotation_Standard = uimenu(app.Menu_Annotation);
            app.Menu_Annotation_Standard.MenuSelectedFcn = createCallbackFcn(app, @Menu_Annotation_StandardSelected, true);
            app.Menu_Annotation_Standard.Checked = 'on';
            app.Menu_Annotation_Standard.Text = 'Standard';
            app.Menu_Annotation_Assisted = uimenu(app.Menu_Annotation);
            app.Menu_Annotation_Assisted.MenuSelectedFcn = createCallbackFcn(app, @Menu_Annotation_AssistedSelected, true);
            app.Menu_Annotation_Assisted.Text = 'Assisted';
            app.Segmentation = uimenu(app.figure1);
            app.Segmentation.Text = 'Segmentation';
            app.ExportAnnotationsMenu = uimenu(app.Segmentation);
            app.ExportAnnotationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportAnnotationsMenuSelected, true);
            app.ExportAnnotationsMenu.Text = 'Export Annotations';
            app.TrainNewModelMenu = uimenu(app.Segmentation);
            app.TrainNewModelMenu.MenuSelectedFcn = createCallbackFcn(app, @TrainNewModelMenuSelected, true);
            app.TrainNewModelMenu.Text = 'Train New Model';
            app.ImageSegmentationMenu = uimenu(app.Segmentation);
            app.ImageSegmentationMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageSegmentationMenuSelected, true);
            app.ImageSegmentationMenu.Text = 'Image Segmentation';
            app.DatasetSegmentationMenu = uimenu(app.Segmentation);
            app.DatasetSegmentationMenu.Text = 'Dataset Segmentation';
            app.CleanSegmentationMenu = uimenu(app.Segmentation);
            app.CleanSegmentationMenu.Text = 'Clean Segmentation';
            app.OptionsMenu = uimenu(app.Segmentation);
            app.OptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsMenuSelected, true);
            app.OptionsMenu.Text = 'Options';
            app.ClassificationMenu = uimenu(app.figure1);
            app.ClassificationMenu.Text = 'Classification';
            app.ExportClassifierMenu = uimenu(app.ClassificationMenu);
            app.ExportClassifierMenu.Text = 'Export Classifier';
            app.TrainClassifierMenu = uimenu(app.ClassificationMenu);
            app.TrainClassifierMenu.Text = 'Train Classifier';
            app.PredictMenu = uimenu(app.ClassificationMenu);
            app.PredictMenu.Checked = 'on';
            app.PredictMenu.Text = 'Predict';
            app.OptionsMenu_2 = uimenu(app.ClassificationMenu);
            app.OptionsMenu_2.Text = 'Options';
            app.Menu_Help = uimenu(app.figure1);
            app.Menu_Help.Text = 'Help';
            app.Menu_Help.Tag = 'Menu_Help';
            app.Menu_Help_About = uimenu(app.Menu_Help);
            app.Menu_Help_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_Help_About_Callback, true);
            app.Menu_Help_About.Text = 'About us';
            app.Menu_Help_About.Tag = 'Menu_Help_About';
            app.but_Left = uibutton(app.figure1, 'push');
            app.but_Left.ButtonPushedFcn = createCallbackFcn(app, @but_Left_Callback, true);
            app.but_Left.Tag = 'but_Left';
            app.but_Left.Text = '<<';
            app.but_Right = uibutton(app.figure1, 'push');
            app.but_Right.ButtonPushedFcn = createCallbackFcn(app, @but_Right_Callback, true);
            app.but_Right.Tag = 'but_Right';
            app.but_Right.Text = '>>';
            app.text_Num = uilabel(app.figure1);
            app.text_Num.Tag = 'text_Num';
            app.text_Num.Text = ' Image number ';
            app.text_Name = uilabel(app.figure1);
            app.text_Name.Tag = 'text_Name';
            app.text_Name.Text = ' Image name';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Enable = 'off';
            app.text5.Text = 'CURRENT SELECTED COMET';
            app.but_CometAdd = uibutton(app.uipanel1, 'push');
            app.but_CometAdd.ButtonPushedFcn = createCallbackFcn(app, @but_CometAdd_Callback, true);
            app.but_CometAdd.Tag = 'but_CometAdd';
            app.but_CometAdd.Text = 'Add comet to class:';
            app.but_CometRemove = uibutton(app.uipanel1, 'push');
            app.but_CometRemove.ButtonPushedFcn = createCallbackFcn(app, @but_CometRemove_Callback, true);
            app.but_CometRemove.Tag = 'but_CometRemove';
            app.but_CometRemove.Text = 'Remove comet';
            app.pop_class = uidropdown(app.uipanel1);
            app.pop_class.Items = {'~No Class~'};
            app.pop_class.ValueChangedFcn = createCallbackFcn(app, @pop_class_Callback, true);
            app.pop_class.Tag = 'pop_class';
            app.pop_class.Value = '~No Class~';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Enable = 'off';
            app.text6.Text = {'threshold'; '                       '};
            app.but_th_less = uibutton(app.uipanel1, 'push');
            app.but_th_less.ButtonPushedFcn = createCallbackFcn(app, @but_th_less_Callback, true);
            app.but_th_less.Tag = 'but_th_less';
            app.but_th_less.Text = '-';
            app.but_th_plus = uibutton(app.uipanel1, 'push');
            app.but_th_plus.ButtonPushedFcn = createCallbackFcn(app, @but_th_plus_Callback, true);
            app.but_th_plus.Tag = 'but_th_plus';
            app.but_th_plus.Text = '+';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Enable = 'off';
            app.text7.Text = 'dilation';
            app.but_di_less = uibutton(app.uipanel1, 'push');
            app.but_di_less.ButtonPushedFcn = createCallbackFcn(app, @but_di_less_Callback, true);
            app.but_di_less.Tag = 'but_di_less';
            app.but_di_less.Text = '-';
            app.but_di_plus = uibutton(app.uipanel1, 'push');
            app.but_di_plus.ButtonPushedFcn = createCallbackFcn(app, @but_di_plus_Callback, true);
            app.but_di_plus.Tag = 'but_di_plus';
            app.but_di_plus.Text = '+';
            app.but_SelectComet = uibutton(app.uipanel1, 'push');
            app.but_SelectComet.ButtonPushedFcn = createCallbackFcn(app, @but_SelectComet_Callback, true);
            app.but_SelectComet.Tag = 'but_SelectComet';
            app.but_SelectComet.Text = 'Select a comet:';
            app.pop_tail = uidropdown(app.uipanel1);
            app.pop_tail.Items = {'without tail', 'without head', 'with tail and head'};
            app.pop_tail.ValueChangedFcn = createCallbackFcn(app, @pop_tail_Callback, true);
            app.pop_tail.Tag = 'pop_tail';
            app.pop_tail.Value = 'without tail';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Enable = 'off';
            app.text8.Text = 'threshold modality:';
            app.pop_threshold = uidropdown(app.uipanel1);
            app.pop_threshold.Items = {'Otsu threshold', 'Triangle threshold', 'Average between Otsu and Triangle'};
            app.pop_threshold.ValueChangedFcn = createCallbackFcn(app, @pop_threshold_Callback, true);
            app.pop_threshold.Tag = 'pop_threshold';
            app.pop_threshold.Value = 'Otsu threshold';
            app.text_Threshold = uibutton(app.uipanel1, 'push');
            app.text_Threshold.ButtonPushedFcn = createCallbackFcn(app, @text_Threshold_Callback, true);
            app.text_Threshold.Tag = 'text_Threshold';
            app.text_Threshold.Text = '0';
            app.text_Dilation = uibutton(app.uipanel1, 'push');
            app.text_Dilation.ButtonPushedFcn = createCallbackFcn(app, @text_Dilation_Callback, true);
            app.text_Dilation.Tag = 'text_Dilation';
            app.text_Dilation.Text = '0';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Enable = 'off';
            app.text11.Text = 'PARAMETERS FOR COMET SEGMENTATION';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Enable = 'off';
            app.text12.Text = 'PARAMETERS FOR HEAD SEGMENTATION';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Enable = 'off';
            app.text13.Text = {'threshold'; '                       '};
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Enable = 'off';
            app.text14.Text = {'dilation'; '                       '};
            app.but_thHead_less = uibutton(app.uipanel1, 'push');
            app.but_thHead_less.ButtonPushedFcn = createCallbackFcn(app, @but_thHead_less_Callback, true);
            app.but_thHead_less.Tag = 'but_thHead_less';
            app.but_thHead_less.Text = '-';
            app.but_thHead_plus = uibutton(app.uipanel1, 'push');
            app.but_thHead_plus.ButtonPushedFcn = createCallbackFcn(app, @but_thHead_plus_Callback, true);
            app.but_thHead_plus.Tag = 'but_thHead_plus';
            app.but_thHead_plus.Text = '+';
            app.text_ThresholdHead = uibutton(app.uipanel1, 'push');
            app.text_ThresholdHead.ButtonPushedFcn = createCallbackFcn(app, @text_ThresholdHead_Callback, true);
            app.text_ThresholdHead.Tag = 'text_ThresholdHead';
            app.text_ThresholdHead.Text = '0';
            app.but_diHead_less = uibutton(app.uipanel1, 'push');
            app.but_diHead_less.ButtonPushedFcn = createCallbackFcn(app, @but_diHead_less_Callback, true);
            app.but_diHead_less.Tag = 'but_diHead_less';
            app.but_diHead_less.Text = '-';
            app.but_diHead_plus = uibutton(app.uipanel1, 'push');
            app.but_diHead_plus.ButtonPushedFcn = createCallbackFcn(app, @but_diHead_plus_Callback, true);
            app.but_diHead_plus.Tag = 'but_diHead_plus';
            app.but_diHead_plus.Text = '+';
            app.text_DilationHead = uibutton(app.uipanel1, 'push');
            app.text_DilationHead.ButtonPushedFcn = createCallbackFcn(app, @text_DilationHead_Callback, true);
            app.text_DilationHead.Tag = 'text_DilationHead';
            app.text_DilationHead.Text = '0';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Enable = 'off';
            app.text17.Text = 'ADD COMET AND SAVE RESULTS';
            app.check_CometFitFreehand = uicheckbox(app.uipanel1);
            app.check_CometFitFreehand.ValueChangedFcn = createCallbackFcn(app, @check_CometFitFreehand_Callback, true);
            app.check_CometFitFreehand.Tag = 'check_CometFitFreehand';
            app.check_CometFitFreehand.Text = 'comet fitting the freehand selection';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Tag = 'text18';
            app.text18.Enable = 'off';
            app.text18.Text = 'threshold modality:';
            app.pop_thresholdHead = uidropdown(app.uipanel1);
            app.pop_thresholdHead.Items = {'Otsu threshold', 'Triangle threshold', 'Average between Otsu and Triangle'};
            app.pop_thresholdHead.ValueChangedFcn = createCallbackFcn(app, @pop_thresholdHead_Callback, true);
            app.pop_thresholdHead.Tag = 'pop_thresholdHead';
            app.pop_thresholdHead.Value = 'Otsu threshold';
            app.slider_comet_threshold = uislider(app.uipanel1);
            app.slider_comet_threshold.Limits = [0 1];
            app.slider_comet_threshold.MajorTicks = [];
            app.slider_comet_threshold.ValueChangedFcn = createCallbackFcn(app, @slider_comet_threshold_Callback, true);
            app.slider_comet_threshold.MinorTicks = [];
            app.slider_comet_threshold.Tag = 'slider_comet_threshold';
            app.slider_comet_threshold.Value = 0.5;
            app.slider_comet_Dilation = uislider(app.uipanel1);
            app.slider_comet_Dilation.Limits = [0 1];
            app.slider_comet_Dilation.MajorTicks = [];
            app.slider_comet_Dilation.ValueChangedFcn = createCallbackFcn(app, @slider_comet_Dilation_Callback, true);
            app.slider_comet_Dilation.MinorTicks = [];
            app.slider_comet_Dilation.Tag = 'slider_comet_Dilation';
            app.slider_head_threshold = uislider(app.uipanel1);
            app.slider_head_threshold.Limits = [0 1];
            app.slider_head_threshold.MajorTicks = [];
            app.slider_head_threshold.ValueChangedFcn = createCallbackFcn(app, @slider_head_threshold_Callback, true);
            app.slider_head_threshold.MinorTicks = [];
            app.slider_head_threshold.Tag = 'slider_head_threshold';
            app.slider_head_threshold.Value = 0.5;
            app.slider_head_Dilation = uislider(app.uipanel1);
            app.slider_head_Dilation.Limits = [0 1];
            app.slider_head_Dilation.MajorTicks = [];
            app.slider_head_Dilation.ValueChangedFcn = createCallbackFcn(app, @slider_head_Dilation_Callback, true);
            app.slider_head_Dilation.MinorTicks = [];
            app.slider_head_Dilation.Tag = 'slider_head_Dilation';
            app.ClassBrowserButton = uibutton(app.uipanel1, 'push');
            app.ClassBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @ClassBrowserButtonPushed, true);
            app.ClassBrowserButton.Text = 'Class Browser';
            app.Class_Create_Button = uibutton(app.uipanel1, 'push');
            app.Class_Create_Button.ButtonPushedFcn = createCallbackFcn(app, @Class_Create_ButtonPushed, true);
            app.Class_Create_Button.Tag = 'CreateNewCLass';
            app.Class_Create_Button.Tooltip = {'Create New Class'};
            app.Class_Create_Button.Text = '+';
            app.Class_Remove_Button = uibutton(app.uipanel1, 'push');
            app.Class_Remove_Button.ButtonPushedFcn = createCallbackFcn(app, @Class_Remove_ButtonPushed, true);
            app.Class_Remove_Button.Tag = 'but_diHead_less';
            app.Class_Remove_Button.Tooltip = {'Remove Class'};
            app.Class_Remove_Button.Text = '-';
            app.scope = uiimage(app.uipanel1);
            app.but_ShowOriImg = uibutton(app.figure1, 'push');
            app.but_ShowOriImg.ButtonPushedFcn = createCallbackFcn(app, @but_ShowOriImg_Callback, true);
            app.but_ShowOriImg.Tag = 'but_ShowOriImg';
            app.but_ShowOriImg.Text = 'Show original image';
            app.Panel = uipanel(app.figure1);
            app.axes1 = uiaxes(app.Panel);
            app.axes1.XTick = [];
            app.axes1.XTickLabel = '';
            app.axes1.YTick = [];
            app.axes1.YTickLabel = '';
            app.axes1.Tag = 'axes1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CometAnalyser_GUI_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)CometAnalyser_GUI_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end