classdef segmentationOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        segmentationOptionsUIFigure     matlab.ui.Figure
        ApplyButton                     matlab.ui.control.Button
        ExecutionEnvironmentDropDown    matlab.ui.control.DropDown
        ExecutionEnvironmentDropDownLabel  matlab.ui.control.Label
        ValidationPatienceEditField     matlab.ui.control.EditField
        ValidationPatienceEditFieldLabel  matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.EditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        MaxEpochEditField               matlab.ui.control.EditField
        MaxEpochEditFieldLabel          matlab.ui.control.Label
        InitialLearnRateEditField       matlab.ui.control.EditField
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        SelectmodelButton               matlab.ui.control.Button
        PretrainedModelEditField        matlab.ui.control.EditField
        PretrainedModelEditFieldLabel   matlab.ui.control.Label
        SegmentationTrainingOptionsLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainApp
    end
    
    properties (Access = private)
        validParameters = 1 % Indicator of parameter correctness
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;
            app.PretrainedModelEditField.Value = app.mainApp.comet_handles.segmentationOptions.modelPath;
            app.MaxEpochEditField.Value = app.mainApp.comet_handles.segmentationOptions.MaxEpoch;
            app.MiniBatchSizeEditField.Value = app.mainApp.comet_handles.segmentationOptions.MiniBatchSize;
            app.ValidationPatienceEditField.Value = app.mainApp.comet_handles.segmentationOptions.ValidationPatience;
            app.ExecutionEnvironmentDropDown.Value = app.mainApp.comet_handles.segmentationOptions.ExecutionEnvironment;
            app.InitialLearnRateEditField.Value = app.mainApp.comet_handles.segmentationOptions.InitialLearnRate;
        end

        % Button pushed function: SelectmodelButton
        function SelectmodelButtonPushed(app, event)
            [fileName, path] = uigetfile('Select a pre-trained model!');
            if ischar(path)
                app.PretrainedModelEditField.Value = fullfile(path,fileName);
            end
        end

        % Close request function: segmentationOptionsUIFigure
        function segmentationOptionsUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: InitialLearnRateEditField
        function InitialLearnRateEditFieldValueChanged(app, event)
            value = str2double(app.InitialLearnRateEditField.Value);
            if isempty(value) || numel(value) > 1 || isnan(value) || ~isnumeric(value) || value<1e-6 || value>1 || numel(value) > 1
                warndlg('Please enter a value between 1 and 1^(-6).','Invalid value')
                app.InitialLearnRateEditField.Value = '';
                app.InitialLearnRateEditField.BackgroundColor = [1 .4 .4];
                app.validParameters = 0;
            else
                app.InitialLearnRateEditField.BackgroundColor = [1 1 1];
                app.validParameters = 1;
            end
        end

        % Value changed function: MaxEpochEditField
        function MaxEpochEditFieldValueChanged(app, event)
            value = str2double(app.MaxEpochEditField.Value);
            if isempty(value) || numel(value) > 1 || isnan(value) || ~isnumeric(value) || value<1 || value>10000 || mod(value,1) ~= 0
                warndlg('Please enter an integer value between 1 and 10000.','Invalid value')
                app.MaxEpochEditField.Value = '';
                app.MaxEpochEditField.BackgroundColor = [1 .4 .4];
                app.validParameters = 0;
            else
                app.MaxEpochEditField.BackgroundColor = [1 1 1];
                app.validParameters = 1;
            end
        end

        % Value changed function: MiniBatchSizeEditField
        function MiniBatchSizeEditFieldValueChanged(app, event)
            value = str2double(app.MiniBatchSizeEditField.Value);
            if isempty(value) || numel(value) > 1 || isnan(value) || ~isnumeric(value) || value<1 || mod(value,1) ~= 0
                warndlg('Please enter an integer value greater then 1.','Invalid value')
                app.MiniBatchSizeEditField.Value = '';
                app.MiniBatchSizeEditField.BackgroundColor = [1 .4 .4];
                app.validParameters = 0;
            else
                app.MiniBatchSizeEditField.BackgroundColor = [1 1 1];
                app.validParameters = 1;
            end
        end

        % Value changed function: ValidationPatienceEditField
        function ValidationPatienceEditFieldValueChanged(app, event)
            value = str2double(app.ValidationPatienceEditField.Value);
            if isempty(value) || numel(value) > 1 || isnan(value) || ~isnumeric(value) || value<1 || mod(value,1) ~= 0
                warndlg('Please enter an integer value greater then 1.','Invalid value')
                app.ValidationPatienceEditField.Value = '';
                app.ValidationPatienceEditField.BackgroundColor = [1 .4 .4];
                app.validParameters = 0;
            else
                app.ValidationPatienceEditField.BackgroundColor = [1 1 1];
                app.validParameters = 1;
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.validParameters == 1
                app.mainApp.comet_handles.segmentationOptions.modelPath = app.PretrainedModelEditField.Value;
                app.mainApp.comet_handles.segmentationOptions.MaxEpoch = app.MaxEpochEditField.Value;
                app.mainApp.comet_handles.segmentationOptions.MiniBatchSize = app.MiniBatchSizeEditField.Value;
                app.mainApp.comet_handles.segmentationOptions.ValidationPatience = app.ValidationPatienceEditField.Value;
                app.mainApp.comet_handles.segmentationOptions.ExecutionEnvironment = app.ExecutionEnvironmentDropDown.Value;
                app.mainApp.comet_handles.segmentationOptions.InitialLearnRate = app.InitialLearnRateEditField.Value;
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.segmentationOptionsUIFigure = uifigure('Visible', 'off');
            app.segmentationOptionsUIFigure.Name = 'MATLAB App';
            app.segmentationOptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @segmentationOptionsUIFigureCloseRequest, true);
            app.SegmentationTrainingOptionsLabel = uilabel(app.segmentationOptionsUIFigure);
            app.SegmentationTrainingOptionsLabel.Text = 'Segmentation - Training Options';
            app.PretrainedModelEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.PretrainedModelEditFieldLabel.Text = 'Pre-trained Model';
            app.PretrainedModelEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.PretrainedModelEditField.Value = 'Default';
            app.SelectmodelButton = uibutton(app.segmentationOptionsUIFigure, 'push');
            app.SelectmodelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmodelButtonPushed, true);
            app.SelectmodelButton.Text = 'Select model';
            app.InitialLearnRateEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.InitialLearnRateEditFieldLabel.Text = 'Initial Learn Rate';
            app.InitialLearnRateEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.InitialLearnRateEditField.ValueChangedFcn = createCallbackFcn(app, @InitialLearnRateEditFieldValueChanged, true);
            app.InitialLearnRateEditField.Value = '0.004';
            app.MaxEpochEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.MaxEpochEditFieldLabel.Text = 'Max Epoch';
            app.MaxEpochEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.MaxEpochEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEpochEditFieldValueChanged, true);
            app.MaxEpochEditField.Value = '50';
            app.MiniBatchSizeEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.MiniBatchSizeEditFieldLabel.Text = 'Mini Batch Size';
            app.MiniBatchSizeEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.MiniBatchSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MiniBatchSizeEditFieldValueChanged, true);
            app.MiniBatchSizeEditField.Value = '2';
            app.ValidationPatienceEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.ValidationPatienceEditFieldLabel.Text = 'Validation Patience';
            app.ValidationPatienceEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.ValidationPatienceEditField.ValueChangedFcn = createCallbackFcn(app, @ValidationPatienceEditFieldValueChanged, true);
            app.ValidationPatienceEditField.Value = '10';
            app.ExecutionEnvironmentDropDownLabel = uilabel(app.segmentationOptionsUIFigure);
            app.ExecutionEnvironmentDropDownLabel.Text = 'Execution Environment';
            app.ExecutionEnvironmentDropDown = uidropdown(app.segmentationOptionsUIFigure);
            app.ExecutionEnvironmentDropDown.Items = {'auto', 'cpu', 'gpu', 'multi-gpu'};
            app.ExecutionEnvironmentDropDown.Value = 'auto';
            app.ApplyButton = uibutton(app.segmentationOptionsUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.segmentationOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentationOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.segmentationOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.segmentationOptionsUIFigure)
        end
    end
end